classdef Data_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FitParameterTrackingForComplexLorentzianPeaksLabel  matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        DirectoryEditFieldLabel        matlab.ui.control.Label
        DirectoryEditField             matlab.ui.control.EditField
        UseDirectoryButton             matlab.ui.control.Button
        FindDirectoryButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        BusyProcessingLampLabel        matlab.ui.control.Label
        BusyProcessingLamp             matlab.ui.control.Lamp
        PlottingPanel                  matlab.ui.container.Panel
        SubplotsButton                 matlab.ui.control.Button
        ReprocessButton                matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        SeperatePlotButton             matlab.ui.control.Button
        FollowPeaksCheckBox            matlab.ui.control.CheckBox
        PreviousButton                 matlab.ui.control.Button
        LegendCheckBox                 matlab.ui.control.CheckBox
        HoldAxisCheckBox               matlab.ui.control.CheckBox
        removeticksCheckBox            matlab.ui.control.CheckBox
        ExportDataButton               matlab.ui.control.Button
        LabelPeaksCheckBox             matlab.ui.control.CheckBox
        ExportRPRButton                matlab.ui.control.Button
        PeakSettingsPanel              matlab.ui.container.Panel
        PeakFindingButton              matlab.ui.control.Button
        PeakFittingButton              matlab.ui.control.Button
        ExecutionTimeEditFieldLabel    matlab.ui.control.Label
        ExecutionTimeEditField         matlab.ui.control.EditField
        sLabel                         matlab.ui.control.Label
        LastOperationEditFieldLabel    matlab.ui.control.Label
        LastOperationEditField         matlab.ui.control.EditField
        PeaksFoundCountEditFieldLabel  matlab.ui.control.Label
        PeaksFoundCountEditField       matlab.ui.control.NumericEditField
        ProcessAllFilesButton          matlab.ui.control.Button
        PeakTrackingPanel              matlab.ui.container.Panel
        TrackPeaksButton               matlab.ui.control.Button
        AnalyzeTrackedButton           matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        WhatToPlotTab                  matlab.ui.container.Tab
        DataPanel                      matlab.ui.container.Panel
        RawDataCheckBox                matlab.ui.control.CheckBox
        MagnitudeDataCheckBox          matlab.ui.control.CheckBox
        PeaksPanel                     matlab.ui.container.Panel
        RawPeakCheckBox                matlab.ui.control.CheckBox
        MagnitudePeakCheckBox          matlab.ui.control.CheckBox
        TrackedPeakCheckBox            matlab.ui.control.CheckBox
        PeakStateLamp                  matlab.ui.control.Lamp
        FitsPanel                      matlab.ui.container.Panel
        RawFitCheckBox                 matlab.ui.control.CheckBox
        MagnitudeFitCheckBox           matlab.ui.control.CheckBox
        FitStateLamp                   matlab.ui.control.Lamp
        ProcessDataTab                 matlab.ui.container.Tab
        ProcessPanel                   matlab.ui.container.Panel
        DetrendCheckBox                matlab.ui.control.CheckBox
        SmoothCheckBox                 matlab.ui.control.CheckBox
        DPlottingTab                   matlab.ui.container.Tab
        DPlotButton                    matlab.ui.control.Button
        SortTab                        matlab.ui.container.Tab
        Tree2                          matlab.ui.container.Tree
        SortingFactorsNode             matlab.ui.container.TreeNode
        clearButton                    matlab.ui.control.Button
        ChooseSortFactorLabel          matlab.ui.control.Label
        ChooseSortFactorListBox        matlab.ui.control.ListBox
        DropDown                       matlab.ui.control.DropDown
        Image                          matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
    end


    properties (Access = private)

        dir_node  % directory node in tree.
        
        peaks_set_index % keeps track the current given peak being analyzed
       
    end
    
    properties (Access = public)
        
        tdms_data % stores all of the data.
        
        index     % index used to refference the current file.
        
        recalculate % boolean that tells the program to recalculate the fit and peak
                    % data prior to performing a plot.
                    
    end

    methods (Access = private)
    
        function Update_Plot_Info(app)
            % SET: fields in plot_info struct based on the settings 
            %      in the GUI.
            
            % GET: plot_info struct
            %plot_info = Get_Plot_Struct();
            plot_info = app.tdms_data(app.index).plot_info;

            % Plot - Data
            if app.RawDataCheckBox.Value
                plot_info.raw = 1;
            else
                plot_info.raw = 0;
            end
            
            if app.MagnitudeDataCheckBox.Value
               plot_info.mag = 1; 
            else
               plot_info.mag = 0;
            end
            
            % Plot - Peaks
            if app.RawPeakCheckBox.Value
               plot_info.peaks_raw(1) = 1;
            else
               plot_info.peaks_raw(1) = 0; 
            end
            
            if app.MagnitudePeakCheckBox.Value
               plot_info.peaks_mag(1) = 1;
            else
               plot_info.peaks_mag(1) = 0; 
            end
            
            if app.TrackedPeakCheckBox.Value
               plot_info.peaks_tracked = 1; 
            else
               plot_info.peaks_tracked = 0; 
            end
            
            % Plot - Fits
            if app.RawFitCheckBox.Value
               plot_info.peaks_raw(2) = 1;
               app.FollowPeaksCheckBox.Enable = 'On';
            else
               plot_info.peaks_raw(2) = 0;
               app.FollowPeaksCheckBox.Enable = 'Off';
            end
            
            if app.MagnitudeFitCheckBox.Value
               plot_info.peaks_mag(2) = 1; 
            else
               plot_info.peaks_mag(2) = 0; 
            end
            
            % Plot - Process
            plot_info.preprocess = {};
            if app.SmoothCheckBox.Value 
               plot_info.preprocess{end+1}  = @Smooth; 
            end
            
            if app.DetrendCheckBox.Value
                plot_info.preprocess{end+1} = @Detrend;
            end
            
            % Legend
            if app.LegendCheckBox.Value
               plot_info.legend = 1;
            else
                plot_info.legend = 0;
            end
            
            % Labels on Magnitude peaks
            if app.LabelPeaksCheckBox.Value
                plot_info.mag_labels = 1;
            else
                plot_info.mag_labels = 0;
            end
            
            % SET plot_info
            [app.tdms_data.plot_info] = deal(plot_info);
            
        end


        function State_Changed(app)
            % This function is called:
            %   - everytime a new file is processed that has not yet been processed.
            % This function:
            %   - tests whether the new file is being processed differently than the 
            %     other files that have already been processed, and returns a boolean indicator. 

            a =1;
            % CHECK: if file has already been analyzed
            if strcmp(app.dir_node.Children(app.index).Icon,'green_circle.jpeg')
                return
            end
            
            % CHECK: The Current State of the application
            fit_state  = false;
            peak_state = false;
            
            if strcmp(app.FitStateLamp.Enable, 'on')
                fit_state = true;
            end
            if strcmp(app.PeakStateLamp.Enable, 'on')
                peak_state = true;
            end
            
            % CHECK: if fit state has changed
            if (app.RawFitCheckBox.Value || app.MagnitudeFitCheckBox.Value) ~= fit_state
                Set_Recalc_All(app)
            end
            
            % CHECK: if peak state has changed
            if (app.RawFitCheckBox.Value  || app.MagnitudeFitCheckBox.Value   || ...
                app.RawPeakCheckBox.Value || app.MagnitudePeakCheckBox.Value) ~= peak_state
                Set_Recalc_All(app)
            end
            

        end
        
        function Set_Sort_Factors(app)
            % Sets the sorting factors in the SortFactorListBox
            project_config = Get_Project_Struct();
            measurements = project_config.map_measurement_unit.keys;
            app.ChooseSortFactorListBox.Items = [{'None'},measurements];
        end
        
    end

    methods (Access = public)
              
        function Set_Recalc_All(app)
            
            % SET
            app.recalculate = true(1,length(app.tdms_data));
            Children = app.dir_node.Children;
            [Children.Icon] = deal('');
            
            % SET STATE
            app.PeakStateLamp.Enable = 'off';
            app.FitStateLamp.Enable  = 'off';
            
            % NOTE: 
            % I should consider 
            % removing all previous peaks found
            
            % removes all previous peaks found
            %peak_data = app.tdms_data(app.index).plot_info.peaks;
            %app.tdms_data(app.index).plot_info.peaks = ...
            %    structfun(@(x) [], peak_data(1), 'UniformOutput', false);
        end
    
        function Reprocess(app,ax)
            
            tic
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Manage State: Peaks
            % SET: all files to recalculate if an indicator is selected that
            %      that turns the lamp on, while the lamp is currently off.
            if strcmp(app.PeakStateLamp.Enable, 'off') && ...
            (app.RawPeakCheckBox.Value || app.MagnitudePeakCheckBox.Value || ...
             app.RawFitCheckBox.Value  || app.MagnitudeFitCheckBox.Value)
                Set_Recalc_All(app)
                app.PeakStateLamp.Enable = 'on';
            end
            
            % Manage State: Fits
            % SET: all files to recalculate if an indicator is selected that
            %      that turns the lamp on, while the lamp is currently off.
            if strcmp(app.FitStateLamp.Enable, 'off') && ...
             (app.RawFitCheckBox.Value  || app.MagnitudeFitCheckBox.Value)
                Set_Recalc_All(app)
                app.PeakStateLamp.Enable = 'on';
                app.FitStateLamp.Enable  = 'on';
            end
            
            % set info to plot
            Update_Plot_Info(app);
            
            % SET: fields from GUI, and process the data
            if app.recalculate(app.index)
                app.BusyProcessingLamp.Enable = 'on';
                drawnow % refresh
                
                app.tdms_data(app.index) = Process_Data(app.tdms_data(app.index)); 
                app.LastOperationEditField.Value = ['Processed File With ID: ', ...
                                                   app.tdms_data(app.index).id];
                    
                % SET: tree icon
                app.dir_node.Children(app.index).Icon = 'green_circle.jpeg';
                
            else
                app.LastOperationEditField.Value = ['Plotted File With ID: ', ...
                                                   app.tdms_data(app.index).id];
            end
            
            % PLOT:
            Plot_File(ax,app.tdms_data(app.index))
            
            % SET: current spot in filetree
            app.Tree.SelectedNodes = app.dir_node.Children(app.index);
            
            if app.FollowPeaksCheckBox.Value == 1 &&  ...
                    isempty(app.tdms_data(app.index).peaks) ~= 1
                
                % GET: peak being refferenced
                td = app.tdms_data(app.index); % single tdms_data struct, corresponding to the current file
                pt = td.peaks(app.peaks_set_index); % peak struct that is being followed
                
                % GET: min/max freq
                min_freq = min(pt.fit.frequencies);
                max_freq = max(pt.fit.frequencies);
                
                % GET: frequencies in fit range
                freq_bool = min_freq <= td.frequency & td.frequency <= max_freq;
                signal_values = [td.signal_y(freq_bool), td.signal_x(freq_bool)];
                
                % SET: Axis limits to the fit can be zoomed on.
                ylim(ax,[min([signal_values,pt.fit.signal_x,pt.fit.signal_y]), ...
                         max([signal_values,pt.fit.signal_x,pt.fit.signal_y])]);
                xlim(ax,[min(pt.fit.frequencies), max(pt.fit.frequencies)])
                
                % SET: title
                title(ax,{['Temperature: ',num2str(td.temperature),'K'], ...
                          ['Peak (',num2str(app.peaks_set_index),' of ', ...
                          num2str(length(td.peaks)), ')']});
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % SET: lamp off
            if app.recalculate(app.index)
                app.BusyProcessingLamp.Enable = 'off';
            end
            
            % RESET (so as not to have to continually recalculate fits)
            app.recalculate(app.index) = false;
            
            % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc);
            
            % SET: Peaks Found
            app.PeaksFoundCountEditField.Value = length(app.tdms_data(app.index).peaks);
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % index to current tdms_data struct
            app.index = 1;
            
            % When the `follow peaks` check box is pressed,
            % this number keeps track of the currently referenced peak
            app.peaks_set_index = 1;            
            
            % STATE VARIABLES (Lamps):
            % - the lamps keep track of which parameters have
            %   already been calculated, that way the program doesn't
            %   continually recalculate parameters that have have already 
            %   been calculated.
            if app.RawFitCheckBox.Value || app.MagnitudeFitCheckBox.Value
               app.FitStateLamp.Enable  = 'on';
               app.PeakStateLamp.Enable = 'on';
            end
            if app.RawPeakCheckBox.Value || app.MagnitudePeakCheckBox.Value
               app.PeakStateLamp.Enable = 'on'; 
            end
            
            % Set Sorting Fields
            app.Set_Sort_Factors()
            
        end

        % Button pushed function: FindDirectoryButton
        function FindDirectoryButtonPushed(app, event)
            
            % GUI: dir finder 
            selpath = uigetdir('../../2-Files_To_Analyze/');

            % Brings the main figure back into view
            figure(app.UIFigure);
            
            % EXIT: this function exits early if someone pressed cancel
            if selpath == 0
               return
            end
            
            % SET: dir field 
            app.DirectoryEditField.Value = selpath;
            
        end

        % Button pushed function: UseDirectoryButton
        function UseDirectoryButtonPushed(app, event)
            
            tic
            app.BusyProcessingLamp.Enable = 'on';
            drawnow % refresh GUI
            
            try
                
                % DELETE: the old tree if it is not empty
                if isempty(app.dir_node) ~= 1
                    delete(app.dir_node)
                end
                
                % SET: data from tdms files in the given directory
                app.tdms_data = TDMS_Load(app.DirectoryEditField.Value);
                
                % SET: dir node
                [~,dir,~] = fileparts(app.DirectoryEditField.Value);
                app.dir_node = uitreenode(app.Tree,'Text',dir);
                
                % SET: File Nodes in Tree
                for i = 1:length(app.tdms_data)
                    uitreenode(app.dir_node,'Text',app.tdms_data(i).id);
                end
                expand(app.Tree,'All');
                
                % SET: index
                app.index = 1;
                
                % SET: plot info / recalculate
                app.recalculate = true(1,length(app.tdms_data));
                
                % Generate The First Plot
                ReprocessButtonPushed(app);
                
                % SET: last process ran
                app.LastOperationEditField.Value = 'Read In Data';
                drawnow
                
            catch e 

                % SET: Fail Message
                err_msg = 'FAILED: trying to read In data';
                app.LastOperationEditField.Value = err_msg;
                
                % PRINT: error msg
                fprintf(err_msg);
                
                % Turn lamp off
                app.BusyProcessingLamp.Enable = 'off';
                
                % Throw error message
                rethrow(e);
            end
                
            % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc);
            drawnow
            
            % Turn lamp off
            app.BusyProcessingLamp.Enable = 'off';
            
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);
        end

        % Value changed function: RawDataCheckBox
        function RawDataCheckBoxValueChanged(app, event)
            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);
        end

        % Value changed function: MagnitudeDataCheckBox
        function MagnitudeDataCheckBoxValueChanged(app, event)
            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);
        end

        % Value changed function: RawPeakCheckBox
        function RawPeakCheckBoxValueChanged(app, event)
            
            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);
        end

        % Value changed function: MagnitudePeakCheckBox
        function MagnitudePeakCheckBoxValueChanged(app, event)
            
            % SET: peak lables off, otherwise they will display outside
            %      of the plotted region.
            if ~app.MagnitudePeakCheckBox.Value
               app.LabelPeaksCheckBox.Value = false;
            end
            
            % SET: all files to recalculate the lamp was previously off.
            if strcmp(app.PeakStateLamp.Enable, 'off')
                Set_Recalc_All(app)
                app.PeakStateLamp.Enable = 'on';
            end

            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);
        end

        % Value changed function: TrackedPeakCheckBox
        function TrackedPeakCheckBoxValueChanged(app, event)
            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);
        end

        % Value changed function: RawFitCheckBox
        function RawFitCheckBoxValueChanged(app, event)
            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);     
        end

        % Value changed function: MagnitudeFitCheckBox
        function MagnitudeFitCheckBoxValueChanged(app, event)
            
            % SET: all files to recalculate the lamp was previously off.
            if strcmp(app.FitStateLamp.Enable, 'off')
                Set_Recalc_All(app)
                app.FitStateLamp.Enable = 'on';
            end
            
            ReprocessButtonPushed(app);
        end

        % Value changed function: DetrendCheckBox
        function DetrendCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            % CASE: `Follow Peaks` is Set
            if app.FollowPeaksCheckBox.Value == 1 
                
                % CASE: app is not on the last peak
                if app.peaks_set_index < length(app.tdms_data(app.index).peaks)
                    app.peaks_set_index = app.peaks_set_index + 1;
                    ReprocessButtonPushed(app);
                    return
                else
                    % RESET: peaks_set_index
                    app.peaks_set_index = 1;
                end
                
            end
             
            % SET: new index
            if app.index == length(app.tdms_data)
                app.index = 1;
            else
                app.index = app.index + 1;
            end
            
            % Determine whether or not state has been changed
            % Sets Recalc All if it has been changed.
            State_Changed(app)
            
            ReprocessButtonPushed(app);
        end

        % Button pushed function: SeperatePlotButton
        function SeperatePlotButtonPushed(app, event)
            figure();
            Reprocess(app,gca);
        end

        % Value changed function: FollowPeaksCheckBox
        function FollowPeaksCheckBoxValueChanged(app, event)
            app.peaks_set_index = 1;
            ReprocessButtonPushed(app);
        end

        % Button pushed function: SubplotsButton
        function SubplotsButtonPushed(app, event)
            
            % SET: busy on
            tic
            app.BusyProcessingLamp.Enable = 'on';
            drawnow % refresh
            
            % process and plot
            ProcessAllFilesButtonPushed(app);
            Sub_Plots(app.tdms_data);
            
            % SET: busy off
            app.BusyProcessingLamp.Enable = 'off';
            
            % SET: last process ran
            app.LastOperationEditField.Value = 'Read In Data';
            
            % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc);
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            % CASE: `Follow Peaks` is Set
            if app.FollowPeaksCheckBox.Value == 1
                
                % CASE: Peak index > 1
                if app.peaks_set_index > 1
                    app.peaks_set_index = app.peaks_set_index - 1;
                    ReprocessButtonPushed(app);
                    return
                else
                    % RESET: peak index
                    app.peaks_set_index = length(app.tdms_data(app.index).peaks);
                end
            end
            
            % SET: new index
            if app.index == 1
                app.index = length(app.tdms_data);
            else
                app.index = app.index - 1;
            end
            
            % Determine whether or not state has been changed
            State_Changed(app)
            
            ReprocessButtonPushed(app);
            
        end

        % Button pushed function: ProcessAllFilesButton
        function ProcessAllFilesButtonPushed(app, event)
            
            % start timer
            total_time = tic;
            
            % turn on busy processing light
            app.BusyProcessingLamp.Enable = 'on';
            drawnow % refresh gui
            
            % UPDATE: info used for processing data
            Update_Plot_Info(app);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % GET: number of files that need to be reprocessed
            num_files_to_recalc = sum(app.recalculate);
            
            % CREATE: A wait bar
            wb = waitbar(0,"Time Till Completion:");
            
            % time spent processing each file
            file_times = [];
            
            % ITERATE: through every file
            for i = 1:length(app.recalculate)
            
                % SET: fields from GUI, and process the data
                if app.recalculate(i)
                    
                    % timer for processing a file
                    ft = tic;
                    
                    % SET: processed data
                    app.tdms_data(i) =  Process_Data(app.tdms_data(i));
                    
                    % SET: tree icon and the selected tree node
                    app.dir_node.Children(i).Icon = 'green_circle.jpeg';
                    app.Tree.SelectedNodes = app.dir_node.Children(app.index);
                    drawnow % refresh GUI
                    
                    % SET: recalculate boolean
                    app.recalculate(i) = false;
                    
                    % UPDATE: file times
                    file_times = [file_times,toc(ft)];
                    
                    % number of files left to recalculate
                    files_left = num_files_to_recalc - length(file_times);
                    
                    % update the wait bar
                    waitbar(length(file_times) / num_files_to_recalc, ...
                             wb, ...
                             ['Processed Files: ',num2str(length(file_times)), ' of ', num2str(num_files_to_recalc), ...
                             ' Approximate Time Left: ',num2str(mean(file_times)*files_left)],'s')
                end
                
            end 
            
            % CLOSE: wait bar GUI
            close(wb);
            
            % SET: focus back to the main GUI
            figure(app.UIFigure)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % SET: last operation
            app.LastOperationEditField.Value =  ...
                ['Processed ',num2str(num_files_to_recalc),' Files'];
                    
            % SET: lamp to off
            app.BusyProcessingLamp.Enable = 'off';
            
            % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc(total_time));
        end

        % Button pushed function: TrackPeaksButton
        function TrackPeaksButtonPushed(app, event)
            ProcessAllFilesButtonPushed(app);
            Peak_Tracker(app);
        end

        % Button pushed function: AnalyzeTrackedButton
        function AnalyzeTrackedButtonPushed(app, event)
            Peak_Analyzer(app);
        end

        % Callback function
        function findpeaksdocumentationButtonPushed(app, event)
            doc findpeaks
        end

        % Button pushed function: PeakFindingButton
        function PeakFindingButtonPushed(app, event)
            findpeaks_param(app);
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            ReprocessButtonPushed(app);
        end

        % Value changed function: HoldAxisCheckBox
        function HoldAxisCheckBoxValueChanged(app, event)
            value = app.HoldAxisCheckBox.Value;

            if value == true
                for i=1:length(app.tdms_data)
                    app.tdms_data(i).plot_info.XLim = [app.UIAxes.XLim];
                    app.tdms_data(i).plot_info.YLim = [app.UIAxes.YLim];
                end
            else
                for i=1:length(app.tdms_data)
                    app.tdms_data(i).plot_info.XLim = 0;
                    app.tdms_data(i).plot_info.YLim = 0;
                end
            end
            ReprocessButtonPushed(app);
        end

        % Value changed function: removeticksCheckBox
        function removeticksCheckBoxValueChanged(app, event)
            value = app.removeticksCheckBox.Value;
            if value == true
                for i=1:length(app.tdms_data)
                    app.tdms_data(i).plot_info.remove_ticks = 1;
                end
            else
                for i=1:length(app.tdms_data)
                    app.tdms_data(i).plot_info.remove_ticks = 0;
                end
            end
            ReprocessButtonPushed(app);
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Export_Data(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            % GET: the text from the node that was selected
            text = app.Tree.SelectedNodes.Text;
            
            % An error occurs when someone tries to pick a parent node
            % - I use this try catch as a fix towards that.
            app.index = find(string({app.tdms_data.id}) == text);
            if ~isempty(app.index)
                State_Changed(app)
                ReprocessButtonPushed(app);
            else
                disp('Could not plot the selected node ...')
            end
        end

        % Button pushed function: PeakFittingButton
        function PeakFittingButtonPushed(app, event)
            peakfit_param(app);
        end

        % Value changed function: LabelPeaksCheckBox
        function LabelPeaksCheckBoxValueChanged(app, event)
            if app.LabelPeaksCheckBox.Value
                app.MagnitudePeakCheckBox.Value = true;
            end
            ReprocessButtonPushed(app);
        end

        % Button pushed function: DPlotButton
        function DPlotButtonPushed(app, event)
            ProcessAllFilesButtonPushed(app)
            freq_plot(app.tdms_data)
        end

        % Callback function
        function GeneralSettingsButtonPushed(app, event)
            General_Settings(app)
        end

        % Value changed function: ChooseSortFactorListBox
        function ChooseSortFactorListBoxValueChanged(app, event)
            factor = app.ChooseSortFactorListBox.Value;
            
            % Don't perform any operations when the default (None) is selected
            if strcmp(factor,'None') == 1
               return
            end
            
            % Get tree node extension to use
            extension = '';
            if strcmp(app.DropDown.Value, "Ascending") == 1
                extension = '-Ascending';
            else
                extension = '-Descending';
            end
            
            % Update Tree
            uitreenode(app.SortingFactorsNode,'Text',[factor,extension]);
            expand(app.Tree2,'All');
            
            % Update Choose Factor List
            items = app.ChooseSortFactorListBox.Items;
            app.ChooseSortFactorListBox.Items = items(string(items) ~= factor);
            
            % Reset Choose Factor List
            app.ChooseSortFactorListBox.Value = 'None';
            
            % Sort Based On Tree Values
            map = containers.Map({'Ascending','Descending'}, ...
                           {1,-1});
            
            % Parse nodes to get field and direction
            child_nodes = app.SortingFactorsNode.Children;
            directions = [];
            fields    = [];
            for i=1:length(child_nodes)
                sp = split(child_nodes(i).Text,'-');
                fields     = [fields, {sp{1}}];
                directions = [directions, map(sp{2})];
            end
            
            % Sort struct
            [app.tdms_data,indices] = nestedSortStruct(app.tdms_data, fields, directions);
            
            % Make sure the index location remains on the current file
            app.index = find(indices == app.index);
            
            % Maintain which files still need to be calculated
            app.recalculate = app.recalculate(indices);
            
            % RESET: File Nodes in Tree
            delete(app.dir_node.Children)
            for i = 1:length(app.tdms_data)
                uitreenode(app.dir_node,'Text',app.tdms_data(i).id);
            end
            expand(app.Tree,'All');
            
            % Reset Green circles in File Nodes again
            for i=1:length(app.recalculate)
                if app.recalculate(i) == false
                   app.dir_node.Children(i).Icon = 'green_circle.jpeg'; 
                end
            end
            
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            
            % RESET: sort factor list
            app.Set_Sort_Factors()
            
            % RESET: tree of values currently being sorted on.
            delete(app.SortingFactorsNode.Children)
            expand(app.Tree2,'All');
        end

        % Button pushed function: ExportRPRButton
        function ExportRPRButtonPushed(app, event)
            
        
            % file names for the input rpr files
            file_names = {app.tdms_data.id};
            
            % frequencies for each file
            cell_peak_frequencies = {};
            for ii=1:length(app.tdms_data)
                peak_freqs = [app.tdms_data(ii).peaks.Frequencies];
                cell_peak_frequencies = [cell_peak_frequencies, peak_freqs];
            end
            
            % open rpr program
            rpr(file_names, cell_peak_frequencies);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FitParameterTrackingForComplexLorentzianPeaksLabel = uilabel(app.UIFigure);
            app.FitParameterTrackingForComplexLorentzianPeaksLabel.Text = 'Fit Parameter Tracking For Complex Lorentzian Peaks';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.UseDirectoryButton = uibutton(app.UIFigure, 'push');
            app.UseDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @UseDirectoryButtonPushed, true);
            app.UseDirectoryButton.Text = 'Use Directory';
            app.FindDirectoryButton = uibutton(app.UIFigure, 'push');
            app.FindDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @FindDirectoryButtonPushed, true);
            app.FindDirectoryButton.Icon = 'yellow-folder.png';
            app.FindDirectoryButton.Text = 'Find Directory';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BusyProcessingLampLabel = uilabel(app.UIFigure);
            app.BusyProcessingLampLabel.Enable = 'off';
            app.BusyProcessingLampLabel.Text = 'Busy Processing';
            app.BusyProcessingLamp = uilamp(app.UIFigure);
            app.BusyProcessingLamp.Enable = 'off';
            app.PlottingPanel = uipanel(app.UIFigure);
            app.PlottingPanel.Title = 'Plotting';
            app.SubplotsButton = uibutton(app.PlottingPanel, 'push');
            app.SubplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SubplotsButtonPushed, true);
            app.SubplotsButton.Icon = 'grid.png';
            app.SubplotsButton.Text = 'Subplots';
            app.ReprocessButton = uibutton(app.PlottingPanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Icon = 'repeat.png';
            app.ReprocessButton.Text = 'Re-process';
            app.NextButton = uibutton(app.PlottingPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'right_arrow.png';
            app.NextButton.Text = 'Next';
            app.SeperatePlotButton = uibutton(app.PlottingPanel, 'push');
            app.SeperatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeperatePlotButtonPushed, true);
            app.SeperatePlotButton.Icon = 'sepperate.png';
            app.SeperatePlotButton.Text = 'Seperate Plot';
            app.FollowPeaksCheckBox = uicheckbox(app.PlottingPanel);
            app.FollowPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @FollowPeaksCheckBoxValueChanged, true);
            app.FollowPeaksCheckBox.Text = 'Follow Peaks';
            app.PreviousButton = uibutton(app.PlottingPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Icon = 'left_arrow.png';
            app.PreviousButton.Text = 'Previous';
            app.LegendCheckBox = uicheckbox(app.PlottingPanel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.HoldAxisCheckBox = uicheckbox(app.PlottingPanel);
            app.HoldAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldAxisCheckBoxValueChanged, true);
            app.HoldAxisCheckBox.Text = 'Hold Axis';
            app.removeticksCheckBox = uicheckbox(app.PlottingPanel);
            app.removeticksCheckBox.ValueChangedFcn = createCallbackFcn(app, @removeticksCheckBoxValueChanged, true);
            app.removeticksCheckBox.Text = 'remove ticks';
            app.ExportDataButton = uibutton(app.PlottingPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.LabelPeaksCheckBox = uicheckbox(app.PlottingPanel);
            app.LabelPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabelPeaksCheckBoxValueChanged, true);
            app.LabelPeaksCheckBox.Text = 'Label Peaks';
            app.ExportRPRButton = uibutton(app.PlottingPanel, 'push');
            app.ExportRPRButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRPRButtonPushed, true);
            app.ExportRPRButton.Text = 'Export RPR';
            app.PeakSettingsPanel = uipanel(app.UIFigure);
            app.PeakSettingsPanel.Title = 'Peak Settings';
            app.PeakFindingButton = uibutton(app.PeakSettingsPanel, 'push');
            app.PeakFindingButton.ButtonPushedFcn = createCallbackFcn(app, @PeakFindingButtonPushed, true);
            app.PeakFindingButton.Text = 'Peak Finding';
            app.PeakFittingButton = uibutton(app.PeakSettingsPanel, 'push');
            app.PeakFittingButton.ButtonPushedFcn = createCallbackFcn(app, @PeakFittingButtonPushed, true);
            app.PeakFittingButton.Text = 'Peak Fitting';
            app.ExecutionTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExecutionTimeEditFieldLabel.Text = 'Execution Time';
            app.ExecutionTimeEditField = uieditfield(app.UIFigure, 'text');
            app.ExecutionTimeEditField.Editable = 'off';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.LastOperationEditFieldLabel = uilabel(app.UIFigure);
            app.LastOperationEditFieldLabel.Text = 'Last Operation';
            app.LastOperationEditField = uieditfield(app.UIFigure, 'text');
            app.LastOperationEditField.Editable = 'off';
            app.PeaksFoundCountEditFieldLabel = uilabel(app.UIFigure);
            app.PeaksFoundCountEditFieldLabel.Text = 'Peaks Found Count';
            app.PeaksFoundCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeaksFoundCountEditField.Editable = 'off';
            app.ProcessAllFilesButton = uibutton(app.UIFigure, 'push');
            app.ProcessAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllFilesButtonPushed, true);
            app.ProcessAllFilesButton.Text = 'Process All Files';
            app.PeakTrackingPanel = uipanel(app.UIFigure);
            app.PeakTrackingPanel.Title = 'Peak Tracking';
            app.TrackPeaksButton = uibutton(app.PeakTrackingPanel, 'push');
            app.TrackPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @TrackPeaksButtonPushed, true);
            app.TrackPeaksButton.Icon = 'track.png';
            app.TrackPeaksButton.Text = 'Track Peaks';
            app.AnalyzeTrackedButton = uibutton(app.PeakTrackingPanel, 'push');
            app.AnalyzeTrackedButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeTrackedButtonPushed, true);
            app.AnalyzeTrackedButton.Enable = 'off';
            app.AnalyzeTrackedButton.Text = 'Analyze Tracked';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WhatToPlotTab = uitab(app.TabGroup);
            app.WhatToPlotTab.Title = 'What To Plot';
            app.DataPanel = uipanel(app.WhatToPlotTab);
            app.DataPanel.Title = 'Data';
            app.RawDataCheckBox = uicheckbox(app.DataPanel);
            app.RawDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawDataCheckBoxValueChanged, true);
            app.RawDataCheckBox.Text = 'Raw';
            app.RawDataCheckBox.Value = true;
            app.MagnitudeDataCheckBox = uicheckbox(app.DataPanel);
            app.MagnitudeDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagnitudeDataCheckBoxValueChanged, true);
            app.MagnitudeDataCheckBox.Text = 'Magnitude';
            app.PeaksPanel = uipanel(app.WhatToPlotTab);
            app.PeaksPanel.Title = 'Peaks';
            app.RawPeakCheckBox = uicheckbox(app.PeaksPanel);
            app.RawPeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawPeakCheckBoxValueChanged, true);
            app.RawPeakCheckBox.Text = 'Raw';
            app.MagnitudePeakCheckBox = uicheckbox(app.PeaksPanel);
            app.MagnitudePeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagnitudePeakCheckBoxValueChanged, true);
            app.MagnitudePeakCheckBox.Text = 'Magnitude';
            app.TrackedPeakCheckBox = uicheckbox(app.PeaksPanel);
            app.TrackedPeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackedPeakCheckBoxValueChanged, true);
            app.TrackedPeakCheckBox.Enable = 'off';
            app.TrackedPeakCheckBox.Text = 'Tracked';
            app.PeakStateLamp = uilamp(app.PeaksPanel);
            app.PeakStateLamp.Enable = 'off';
            app.FitsPanel = uipanel(app.WhatToPlotTab);
            app.FitsPanel.Title = 'Fits';
            app.RawFitCheckBox = uicheckbox(app.FitsPanel);
            app.RawFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawFitCheckBoxValueChanged, true);
            app.RawFitCheckBox.Text = 'Raw';
            app.RawFitCheckBox.Value = true;
            app.MagnitudeFitCheckBox = uicheckbox(app.FitsPanel);
            app.MagnitudeFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagnitudeFitCheckBoxValueChanged, true);
            app.MagnitudeFitCheckBox.Text = 'Magnitude';
            app.FitStateLamp = uilamp(app.FitsPanel);
            app.FitStateLamp.Enable = 'off';
            app.ProcessDataTab = uitab(app.TabGroup);
            app.ProcessDataTab.Title = 'Process Data';
            app.ProcessPanel = uipanel(app.ProcessDataTab);
            app.ProcessPanel.Title = 'Process';
            app.DetrendCheckBox = uicheckbox(app.ProcessPanel);
            app.DetrendCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetrendCheckBoxValueChanged, true);
            app.DetrendCheckBox.Text = 'Detrend';
            app.DetrendCheckBox.Value = true;
            app.SmoothCheckBox = uicheckbox(app.ProcessPanel);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Text = 'Smooth';
            app.SmoothCheckBox.Value = true;
            app.DPlottingTab = uitab(app.TabGroup);
            app.DPlottingTab.Title = '3D-Plotting';
            app.DPlotButton = uibutton(app.DPlottingTab, 'push');
            app.DPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DPlotButtonPushed, true);
            app.DPlotButton.Text = '3D-Plot';
            app.SortTab = uitab(app.TabGroup);
            app.SortTab.Title = 'Sort';
            app.Tree2 = uitree(app.SortTab);
            app.SortingFactorsNode = uitreenode(app.Tree2);
            app.SortingFactorsNode.Text = 'Sorting-Factors';
            app.clearButton = uibutton(app.SortTab, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.ChooseSortFactorLabel = uilabel(app.SortTab);
            app.ChooseSortFactorLabel.Text = 'Choose Sort Factor';
            app.ChooseSortFactorListBox = uilistbox(app.SortTab);
            app.ChooseSortFactorListBox.Items = {'Item 1', 'Item 2'};
            app.ChooseSortFactorListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseSortFactorListBoxValueChanged, true);
            app.DropDown = uidropdown(app.SortTab);
            app.DropDown.Items = {'Ascending', 'Descending'};
            app.DropDown.Value = 'Ascending';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'colostate.jpg';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'colostate.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end