classdef General_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GeneralSettingsLabel         matlab.ui.control.Label
        sig_x_tree                   matlab.ui.container.Tree
        SignificantxcoordinatesNode  matlab.ui.container.TreeNode
        EditFieldCoordinate          matlab.ui.control.NumericEditField
        AddCoordinateButton          matlab.ui.control.Button
        ClearCoordinatesButton       matlab.ui.control.Button
        Tree                         matlab.ui.container.Tree
        SettingsNode                 matlab.ui.container.TreeNode
        SignificantCoordinatesNode   matlab.ui.container.TreeNode
    end


    properties (Access = private)
        parent_app % refference to the calling application
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
            
            % Add current signicant coordinates as tree nodes. 
            current_coordinates = app.parent_app.tdms_data(1).plot_info.sig_x_coordinates;
            for i = 1:length(current_coordinates)
                % Add Coordinate to Tree
                uitreenode(app.SignificantxcoordinatesNode,'Text',num2str(current_coordinates(i)));
            end
        end

        % Button pushed function: AddCoordinateButton
        function AddCoordinateButtonPushed(app, event)
            new_coordinate = app.EditFieldCoordinate.Value;
            
            % Store New Coordinate
            plot_info = app.parent_app.tdms_data(1).plot_info;
            plot_info.sig_x_coordinates(end+1) = new_coordinate;
            [app.parent_app.tdms_data.plot_info] = deal(plot_info);
            
            % Add Coordinate to Tree
            uitreenode(app.SignificantxcoordinatesNode,'Text',num2str(new_coordinate));
            expand(app.SignificantxcoordinatesNode,'All');
        end

        % Button pushed function: ClearCoordinatesButton
        function ClearCoordinatesButtonPushed(app, event)
            
            % Remove nodes in tree
            delete(app.SignificantxcoordinatesNode.Children)
            
            % Update field 
            plot_info = app.parent_app.tdms_data(1).plot_info;
            plot_info.sig_x_coordinates = [];
            [app.parent_app.tdms_data.plot_info] = deal(plot_info);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GeneralSettingsLabel = uilabel(app.UIFigure);
            app.GeneralSettingsLabel.Text = 'General Settings';
            app.sig_x_tree = uitree(app.UIFigure);
            app.SignificantxcoordinatesNode = uitreenode(app.sig_x_tree);
            app.SignificantxcoordinatesNode.Text = 'Significant x-coordinates';
            app.EditFieldCoordinate = uieditfield(app.UIFigure, 'numeric');
            app.AddCoordinateButton = uibutton(app.UIFigure, 'push');
            app.AddCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @AddCoordinateButtonPushed, true);
            app.AddCoordinateButton.Text = 'Add Coordinate';
            app.ClearCoordinatesButton = uibutton(app.UIFigure, 'push');
            app.ClearCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCoordinatesButtonPushed, true);
            app.ClearCoordinatesButton.Text = 'Clear Coordinates';
            app.Tree = uitree(app.UIFigure);
            app.SettingsNode = uitreenode(app.Tree);
            app.SettingsNode.Text = 'Settings';
            app.SignificantCoordinatesNode = uitreenode(app.SettingsNode);
            app.SignificantCoordinatesNode.Text = 'Significant Coordinates';
        end
    end

    methods (Access = public)

        % Construct app
        function app = General_Settings(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end