classdef Peak_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        PeakAnalyzerLabel            matlab.ui.control.Label
        LeftAxisDropDownLabel        matlab.ui.control.Label
        LeftAxisDropDown             matlab.ui.control.DropDown
        RightAxisDropDownLabel       matlab.ui.control.Label
        RightAxisDropDown            matlab.ui.control.DropDown
        SeperatePlotButton           matlab.ui.control.Button
        Tree                         matlab.ui.container.Tree
        MissingValuesNode            matlab.ui.container.TreeNode
        MissingValuesCountEditFieldLabel  matlab.ui.control.Label
        MissingValuesCountEditField  matlab.ui.control.NumericEditField
        PlotSigXCoordinatesCheckBox  matlab.ui.control.CheckBox
        SetXAxisDropDownLabel        matlab.ui.control.Label
        SetXAxisDropDown             matlab.ui.control.DropDown
        FilterDropDownLabel          matlab.ui.control.Label
        FilterDropDown               matlab.ui.control.DropDown
        PlotMissingValuesCheckBox    matlab.ui.control.CheckBox
        MaxLabel                     matlab.ui.control.Label
        MaxSpinner                   matlab.ui.control.Spinner
        MinLabel                     matlab.ui.control.Label
        MinSpinner                   matlab.ui.control.Spinner
    end


    properties (Access = private)
        parent_app % parent application
        tdms_data_missing = [] % tdms_data structures that do 
                               % not have an associated tracked peak.
        measurment_max_min = containers.Map % map measurment -> [max_value, min_value]
        
    end

    methods (Access = private)
    
        function Plot_Fit_Params(app, ax)
            
            % Get: significant x-coordinates
            % - These are used for plotting vertical lines at interesting coordinates
            sig_x = [];
            if app.PlotSigXCoordinatesCheckBox.Value
                sig_x = app.parent_app.tdms_data(1).plot_info.sig_x_coordinates;
            end
            
            % Get Measurment and Unit of x-axis
            project_config = Get_Project_Struct();
            x_measurment = app.SetXAxisDropDown.Value;
            x_unit = project_config.map_measurement_unit(x_measurment);
            x_axis_lbl = [x_measurment, ' (', x_unit, ')'];
            
            % Use the maximum and minimum spinner values to get
            % a mask of the valid peaks and x-coordinates to use.
            mask = app.filter_peaks();
            
            % Get the peaks and x-coordinates to plot over
            x_cor = [app.parent_app.tdms_data.(x_measurment)];
            peaks_tracked = {app.parent_app.tdms_data.peaks_tracked};
            
            % mask coordinates and peaks
            x_cor = x_cor(mask);
            peaks_tracked = peaks_tracked(mask);
            
            % Plot: based on dropdown settings
            Analyze_Tracked_Peaks(ax, ...
                                 peaks_tracked,  ...
                                 x_cor, ...
                                 x_axis_lbl,                    ...
                                 app.LeftAxisDropDown.Value,    ...
                                 app.RightAxisDropDown.Value)
                                 
            % Plot: vertical line function
            pv = @(x, color) xline(app.UIAxes, x, color);
            
            % Plot: missing values
            if app.PlotMissingValuesCheckBox.Value == true
                arrayfun(@(x)  pv(x.(x_measurment), 'k'), app.tdms_data_missing);
            end
            
            % Plot: significant values
            if app.PlotSigXCoordinatesCheckBox.Value == true
                arrayfun(@(x)  pv(x, 'm'), sig_x);
            end
            
        end
        
        
        function new_value = Get_Next_Spinner_Value(app, value, value_prev)
            % Spinners update values by [-1 or 1].
            % I will check to see whether the spinners value increased
            % or decreased, and based on that information, I will find
            % the next value for the spinner based on what the next
            % measured value is that I have stored. 
            
            % measurment label
            m_lbl = app.FilterDropDown.Value;
            
            measurements = sort(unique([app.parent_app.tdms_data.(m_lbl)]));
            
            % Get Nearest Value Function
            % - Arguments (vector, value)
            % - Returns minimum_difference, nearest_index
            get_nearest = @(vec,v) min(abs(vec-v));
            
            if value >= max(measurements)
                
                new_value = max(measurements);
                
            elseif value <= min(measurements)
                    
                new_value = min(measurements);
                
            elseif value > value_prev
                    
                bigger_values = measurements(measurements > value_prev);
                [~, new_index] = get_nearest(bigger_values, value);
                new_value = bigger_values(new_index);
                
            elseif value < value_prev
                    
                smaller_values = measurements(measurements < value_prev);
                [~, new_index] = get_nearest(smaller_values, value);
                new_value = smaller_values(new_index);   
                
            end
            
        end
            
        
        function tdms_data_mask = filter_peaks(app)
            % RETURNS:
            % * xcor: x-coordinates for the plot that are not filtered out.
            % * peaks: peak structs that are not filterd out.
            
            % alias
            tdms_data = app.parent_app.tdms_data;
            
            % Get Vaild tdms_objects:
            % - here I start off by assuming each struct is valid
            tdms_data_mask = true(1,length(app.parent_app.tdms_data));
            
            % Loop Through tdms_data objects
            project_config = Get_Project_Struct();
            measurements   = project_config.map_measurement_unit.keys;
            for i=1:length(app.parent_app.tdms_data)
                
                % loop through the different measurment types,
                % and check that the tdms struct lies inbetween
                % the maximum and minimum values associated with the
                % measurment.
                for j=1:length(measurements)
                    
                    % check if measurment lies inbetween the max and min.
                    m_lbl = measurements{j};
                    meas  = tdms_data(i).(m_lbl);
                    max_min = app.measurment_max_min(m_lbl);
                    
                    % set valid false if it did not pass the test
                    if meas > max_min(1) || meas < max_min(2)
                        tdms_data_mask(i) = false;
                        break
                    end
                    
                end
                
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            
            % STORE: reffernce to parent application
            app.parent_app = parent_app;
            
            % SET: missing values in missing values tree
            %      and update the missing_values property
            count_missing = 0;
            for i = 1:length(app.parent_app.tdms_data)
                if isempty(app.parent_app.tdms_data(i).peaks_tracked)
                    
                    % add missing values to tree
                    missing_val = app.parent_app.tdms_data(i).id;
                    uitreenode(app.MissingValuesNode,'Text',num2str(missing_val));
                    
                    % store missing tdms_data structs
                    app.tdms_data_missing = [app.tdms_data_missing, app.parent_app.tdms_data(i)];
                    count_missing = count_missing + 1;
                end
            end
            expand(app.Tree,'All');
            app.MissingValuesCountEditField.Value = count_missing;
            
            % Set Drop Down Menus: (Set X-axis) and (Filter)
            project_config = Get_Project_Struct();
            measurements   = project_config.map_measurement_unit.keys;
            app.SetXAxisDropDown.Items = measurements;
            app.FilterDropDown.Items   = measurements;
            
            % Set Drop Down Menus: (Left-Axis and Right-Axis)
            meas = {project_config.file_x_axis_measurment, ...
                    project_config.file_y_axis_measurment};
                
            left_labels = [project_config.fit_lbls, meas];
            right_lables = [{'None'}, left_labels];
            app.LeftAxisDropDown.Items  = left_labels;
            app.RightAxisDropDown.Items = right_lables;
            
            % Set Minimum / Maximum Values For Filter
            for i=1:length(measurements)
                m = measurements{i};
                m_values = [app.parent_app.tdms_data.(m)];
                mn = min(m_values);
                mx = max(m_values);
                app.measurment_max_min(m) = [mx,mn];
            end 
            
            % Set edit fields for Filter
            app.SetFilterFields()
            
            % Plot
            app.Plot_Fit_Params(app.UIAxes);
        end

        % Value changed function: LeftAxisDropDown, 
        % PlotMissingValuesCheckBox, PlotSigXCoordinatesCheckBox, 
        % RightAxisDropDown, SetXAxisDropDown
        function PlotFitParamsLocal(app, event)
            app.Plot_Fit_Params(app.UIAxes);                         
        end

        % Button pushed function: SeperatePlotButton
        function PlotFitParamsSeperate(app, event)
            figure()
            app.Plot_Fit_Params(gca)
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
        
            % Get Previous Maximum and Minimum Values
            value = app.MaxSpinner.Value;
            m_lbl = app.FilterDropDown.Value;
            max_min = app.measurment_max_min(m_lbl);
            
            % Get Next Maximum Value
            max_next = app.Get_Next_Spinner_Value(value, max_min(1));
            
            % Set Next Maximum Value
            app.measurment_max_min(m_lbl) = [max_next, max_min(2)];
            app.MaxSpinner.Value = max_next;
            
            app.PlotFitParamsLocal()
        end

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            
            % Get Previous Maximum and Minimum Values
            value = app.MinSpinner.Value;
            m_lbl = app.FilterDropDown.Value;
            max_min = app.measurment_max_min(m_lbl);
            
            % Get Next Minimum Value
            min_next = app.Get_Next_Spinner_Value(value, max_min(2));
            
            % Set Next Minimum Value
            app.measurment_max_min(m_lbl) = [max_min(1), min_next]; 
            app.MinSpinner.Value = min_next;
            
            app.PlotFitParamsLocal()
        end

        % Value changed function: FilterDropDown
        function SetFilterFields(app, event)
            m_lbl   = app.FilterDropDown.Value;
            max_min = app.measurment_max_min(m_lbl);
            app.MaxSpinner.Value = max_min(1);
            app.MinSpinner.Value = max_min(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PeakAnalyzerLabel = uilabel(app.UIFigure);
            app.PeakAnalyzerLabel.Text = 'Peak Analyzer';
            app.LeftAxisDropDownLabel = uilabel(app.UIFigure);
            app.LeftAxisDropDownLabel.Text = 'Left Axis';
            app.LeftAxisDropDown = uidropdown(app.UIFigure);
            app.LeftAxisDropDown.Items = {'gamma', 'f_0', 'theta', 'A', 'frequency', 'V'};
            app.LeftAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFitParamsLocal, true);
            app.LeftAxisDropDown.Value = 'gamma';
            app.RightAxisDropDownLabel = uilabel(app.UIFigure);
            app.RightAxisDropDownLabel.Text = 'Right Axis';
            app.RightAxisDropDown = uidropdown(app.UIFigure);
            app.RightAxisDropDown.Items = {'None', 'gamma', 'f_0', 'theta', 'A', 'V'};
            app.RightAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFitParamsLocal, true);
            app.RightAxisDropDown.Value = 'None';
            app.SeperatePlotButton = uibutton(app.UIFigure, 'push');
            app.SeperatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFitParamsSeperate, true);
            app.SeperatePlotButton.Text = 'Seperate Plot';
            app.Tree = uitree(app.UIFigure);
            app.MissingValuesNode = uitreenode(app.Tree);
            app.MissingValuesNode.Text = 'Missing Values';
            app.MissingValuesCountEditFieldLabel = uilabel(app.UIFigure);
            app.MissingValuesCountEditFieldLabel.Text = 'Missing Values Count';
            app.MissingValuesCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.MissingValuesCountEditField.Editable = 'off';
            app.PlotSigXCoordinatesCheckBox = uicheckbox(app.UIFigure);
            app.PlotSigXCoordinatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotFitParamsLocal, true);
            app.PlotSigXCoordinatesCheckBox.Text = 'Plot Sig-X-Coordinates';
            app.SetXAxisDropDownLabel = uilabel(app.UIFigure);
            app.SetXAxisDropDownLabel.Text = 'Set X-Axis';
            app.SetXAxisDropDown = uidropdown(app.UIFigure);
            app.SetXAxisDropDown.Items = {};
            app.SetXAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotFitParamsLocal, true);
            app.SetXAxisDropDown.Value = {};
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SetFilterFields, true);
            app.FilterDropDown.Value = {};
            app.PlotMissingValuesCheckBox = uicheckbox(app.UIFigure);
            app.PlotMissingValuesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotFitParamsLocal, true);
            app.PlotMissingValuesCheckBox.Text = 'Plot Missing Values';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = '> Max';
            app.MaxSpinner = uispinner(app.UIFigure);
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = '< Min';
            app.MinSpinner = uispinner(app.UIFigure);
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peak_Analyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end