classdef RPR_Output < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GenerateRPRFilesLabel          matlab.ui.control.Label
        RPRFileTemplateEditFieldLabel  matlab.ui.control.Label
        RPRFileTemplateEditField       matlab.ui.control.EditField
        SelectRPRFileButton            matlab.ui.control.Button
        DirectoryToOutputFilesToEditFieldLabel  matlab.ui.control.Label
        DirectoryToOutputFilesToEditField  matlab.ui.control.EditField
        SelectDirectoryButton          matlab.ui.control.Button
        GenerateRPRInputFilesButton    matlab.ui.control.Button
    end


    properties (Access = private)
        parent_app % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
        end

        % Button pushed function: GenerateRPRInputFilesButton
        function GenerateRPRInputFilesButtonPushed(app, event)
            
            output_folder = app.DirectoryToOutputFilesToEditField.Value;
            rpr_file = app.RPRFileTemplateEditField.Value;
            
            % If Folder or file does not exist, exit early
            if ~exist(output_folder, 'dir') || ~isfile(rpr_file)
                return
            end

            % read files in directory
            % loop through files, and copy with output
            for i=1:length(app.parent_app.tdms_data)
                fnm = app.parent_app.tdms_data(i).file_name;
                [filepath, name, ext] = fileparts(fnm);
                new_file = [output_folder,name,'_rusin.txt'];
                copyfile(rpr_file, new_file, 'f')
                
                % write frequencies to file
                peaks_found = app.parent_app.tdms_data(i).peaks;
                for i=1:length(peaks_found)
                    disp("cool");
                end
            end
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
 
            % GUI: directory finder 
            selpath = uigetdir();

            % EXIT: this function early if someone pressed cancel
            if selpath == 0
                return
            else
                % Brings the main figure back into view
                figure(app.UIFigure);
            end
            
            % SET: dir field 
            app.DirectoryToOutputFilesToEditField.Value = selpath;
        end

        % Button pushed function: SelectRPRFileButton
        function SelectRPRFileButtonPushed(app, event)
            % Get file and path for RPR file. 
            rpr_file = uigetfile;
            
            % EXIT: this function early if someone pressed cancel
            if selpath == 0
                return
            else
                % Brings the main figure back into view
                figure(app.UIFigure);
            end
            
            % SET: dir field 
            app.RPRFileTemplateEditField.Value = rpr_file;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GenerateRPRFilesLabel = uilabel(app.UIFigure);
            app.GenerateRPRFilesLabel.Text = 'Generate RPR Files';
            app.RPRFileTemplateEditFieldLabel = uilabel(app.UIFigure);
            app.RPRFileTemplateEditFieldLabel.Text = 'RPR File Template';
            app.RPRFileTemplateEditField = uieditfield(app.UIFigure, 'text');
            app.SelectRPRFileButton = uibutton(app.UIFigure, 'push');
            app.SelectRPRFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRPRFileButtonPushed, true);
            app.SelectRPRFileButton.Icon = 'yellow-folder.png';
            app.SelectRPRFileButton.Text = 'Select RPR File';
            app.DirectoryToOutputFilesToEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryToOutputFilesToEditFieldLabel.Text = 'Directory To Output Files To';
            app.DirectoryToOutputFilesToEditField = uieditfield(app.UIFigure, 'text');
            app.SelectDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Icon = 'yellow-folder.png';
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.GenerateRPRInputFilesButton = uibutton(app.UIFigure, 'push');
            app.GenerateRPRInputFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRPRInputFilesButtonPushed, true);
            app.GenerateRPRInputFilesButton.Text = 'Generate RPR Input Files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPR_Output(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end