classdef rpr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RPRFileGeneratorLabel           matlab.ui.control.Label
        OpenRPRDescriptionButton        matlab.ui.control.Button
        GenerateFilesButton             matlab.ui.control.Button
        FieldsListBoxLabel              matlab.ui.control.Label
        FieldsListBox                   matlab.ui.control.ListBox
        FieldDescriptionTextAreaLabel   matlab.ui.control.Label
        FieldDescriptionTextArea        matlab.ui.control.TextArea
        FieldValueEditFieldLabel        matlab.ui.control.Label
        FieldValueEditField             matlab.ui.control.EditField
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        RPRrectangularparallelepipedresonatorLabel  matlab.ui.control.Label
        InputFileHeaderPreviewTextAreaLabel  matlab.ui.control.Label
        InputFileHeaderPreviewTextArea  matlab.ui.control.TextArea
        DirectoryButton                 matlab.ui.control.Button
        DirectoryEditField              matlab.ui.control.EditField
        CompoundEditFieldLabel          matlab.ui.control.Label
        CompoundEditField               matlab.ui.control.EditField
        prependstofilenamesLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        field_info % struct (fields: desc (description), 
                   %                 default   
                   %                 value)
                   
        file_names  = {'init'} % file names to use when 
                               % generating input files (without extension)
                               
        cell_frequencies = {} % cell array, where each elemnts is a list of cell_frequencies
                              % that are associated with the filenames via index
    end
    
    methods (Access = private)
        
        function output = generate_output(app)
        
            output = '';
            
            for i=1:length(app.field_info)
                
                extra_char = '';
                
                if i == 9
                    extra_char = newline;
                end
                
                if i == 10
                    extra_char = [newline, ' '];
                end
                
                output = [output, extra_char,app.field_info(i).value, ' '];
                
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, file_names, cell_frequencies)
            
            % store arguments
            if nargin == 3
                app.file_names  = file_names;
                app.cell_frequencies = cell_frequencies;
            end
        
            % --- SET FIELD  ---

            % 1.) number of moduli
            info(1).default = '2';
            info(1).desc    = ['Input: integer (9 max)' newline '* Ex: ' info(1).default newline 'number of moduli'];
            
            % set the rest of field values (Temporarily)
            for i=2:10
                info(i) = info(1);
            end
            
            % 2.) number of dimensions
            info(2).default = '0';
            info(2).desc    = ['Input: integer (0 or 3)' newline '* Ex: ' info(2).default newline 'Number of dimension to be fit.' newline ' * Only the RPR code fits dimensions.' newline ' * So it should be set to 0 for the cylinder and sphere input files.'];
            
            % 3.) order of the basis set
            info(3).default = '12';
            info(3).desc    = ['Input: integer (16 max)' newline '* Ex: ' info(3).default];
            
            % 4.) indicate fit
            info(4).default = '0';
            info(4).desc    = '1 indicates output to screen (0 suppresses it).';
            
            % 5.) mass
            info(5).default = '1.3396';
            info(5).desc    = 'mass in grams';
            
            % 6.) convergence parameter
            info(6).default = '1.00';
            info(6).desc = 'convergence parameter-any positive real number works, the higher it is, the slower and more stable the convergence';
            
            % 7.) output to screen
            info(7).default = '1.00';
            info(7).desc = '1 indicates output to screen (0 suppresses it).';
            
            % 8.) moduli free to float
            info(8).default = '0 0 0 0 0 0 0 0 0';
            info(8).desc = 'the next nine 0s indicate that all moduli are free to float. Setting a 0 to 1 will lock that modulus to the initial guess–all moduli in this line are the orthorhombic equivalent. This is not implemented in the sphere code.';
            
            % 9.) guesses
            info(9).default = '0.5 0.16';
            info(9).desc = '0.5 and 0.16 are the guesses for c11 and c44 in 1012 dynes/cm2–for more moduli, one pass of the code will indicate which moduli are free in rusout.dat.';
            
            % 10.) Dimensions
            info(10).default = '0.474481 0.377003 0.484186';
            info(10).desc    = ['Input: 3 floatig point numbers.' newline 'Ex: ' info(10).default];
            
            % set values to defaults
            [info.value] = deal(info.default);
            
            % store info as class variable
            app.field_info = info;
            
            % associate field  with labels.
            app.FieldsListBox.ItemsData = 1:10;
            
            % update the preview field
            app.InputFileHeaderPreviewTextArea.Value = app.generate_output();
            
        end

        % Value changed function: FieldsListBox
        function FieldsListBoxValueChanged(app, event)
            
            % index: from listbox
            index = app.FieldsListBox.Value;
            
            % update: description
            app.FieldDescriptionTextArea.Value = app.field_info(index).desc;
            
            % update: field value
            app.FieldValueEditField.Value = app.field_info(index).value;
        end

        % Value changed function: FieldValueEditField
        function FieldValueEditFieldValueChanged(app, event)
            
            % field value
            new_value = app.FieldValueEditField.Value;
            
            % index from list box
            index = app.FieldsListBox.Value;
            
            % set new value
            app.field_info(index).value = new_value;
            
            % update the preview field
            app.InputFileHeaderPreviewTextArea.Value = app.generate_output();
        
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            value = app.FieldsListBox.Value;
            if value == length(app.FieldsListBox.ItemsData)
                return
            end
            app.FieldsListBox.Value = value + 1;
            app.FieldsListBoxValueChanged();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            value = app.FieldsListBox.Value;
            if value == 1
                return
            end
            app.FieldsListBox.Value = value - 1;
            app.FieldsListBoxValueChanged();
        end

        % Button pushed function: GenerateFilesButton
        function GenerateFilesButtonPushed(app, event)
            
            % for each file
            for ii=1:length(app.file_names)
                    
                % get template
                general_text = app.InputFileHeaderPreviewTextArea.Value;
               
                % Check if any frequencies were passed in
                if ~isempty(app.cell_frequencies)
                    
                    % refference frequencies
                    frequencies = app.cell_frequencies{ii};
                    
                    % append frequencies
                    for j=1:length(frequencies)
                        general_text = [general_text; [' ', num2str(frequencies(j)), ' 0. 1.00']];
                    end
                    
                end
                
                % file to wright to
                file_with_direc = fullfile(app.DirectoryEditField.Value, [app.CompoundEditField.Value,' - ', app.file_names{ii}]);
                
                % write file
                fileID = fopen(file_with_direc,'w');
                for ii=1:length(general_text)
                    fprintf(fileID,'%s\n',general_text{ii,:});
                end
                fclose(fileID);
                
            end
            
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            direc = uigetdir();
            app.DirectoryEditField.Value = direc;
        end

        % Button pushed function: OpenRPRDescriptionButton
        function OpenRPRDescriptionButtonPushed(app, event)
            open('RPR/rpr_guide.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RPRFileGeneratorLabel = uilabel(app.UIFigure);
            app.RPRFileGeneratorLabel.Text = 'RPR File Generator';
            app.OpenRPRDescriptionButton = uibutton(app.UIFigure, 'push');
            app.OpenRPRDescriptionButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRPRDescriptionButtonPushed, true);
            app.OpenRPRDescriptionButton.Text = 'Open RPR Description';
            app.GenerateFilesButton = uibutton(app.UIFigure, 'push');
            app.GenerateFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFilesButtonPushed, true);
            app.GenerateFilesButton.Text = 'Generate Files';
            app.FieldsListBoxLabel = uilabel(app.UIFigure);
            app.FieldsListBoxLabel.Text = 'Fields';
            app.FieldsListBox = uilistbox(app.UIFigure);
            app.FieldsListBox.Items = {'Number of Moduli (9 max)', 'Number of dimension to be fit (0 or 3)', 'Order of the basis set (16 max)', 'Indicate a fit (any integer 0-600)', 'mass (in grams)', 'convergence parameter (any positive real number)', 'output to screen (0 or 1)', 'moduli free to float (9 0''s or 1s)', 'guesses for c11 and c44 (2 floating point #''s)', 'Dimensions'};
            app.FieldsListBox.ValueChangedFcn = createCallbackFcn(app, @FieldsListBoxValueChanged, true);
            app.FieldsListBox.Value = 'Number of Moduli (9 max)';
            app.FieldDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.FieldDescriptionTextAreaLabel.Text = 'Field Description';
            app.FieldDescriptionTextArea = uitextarea(app.UIFigure);
            app.FieldDescriptionTextArea.Editable = 'off';
            app.FieldValueEditFieldLabel = uilabel(app.UIFigure);
            app.FieldValueEditFieldLabel.Text = 'Field Value';
            app.FieldValueEditField = uieditfield(app.UIFigure, 'text');
            app.FieldValueEditField.ValueChangedFcn = createCallbackFcn(app, @FieldValueEditFieldValueChanged, true);
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.RPRrectangularparallelepipedresonatorLabel = uilabel(app.UIFigure);
            app.RPRrectangularparallelepipedresonatorLabel.Text = 'RPR: rectangular parallelepiped resonator';
            app.InputFileHeaderPreviewTextAreaLabel = uilabel(app.UIFigure);
            app.InputFileHeaderPreviewTextAreaLabel.Text = 'Input File Header Preview';
            app.InputFileHeaderPreviewTextArea = uitextarea(app.UIFigure);
            app.DirectoryButton = uibutton(app.UIFigure, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Icon = 'yellow-folder.png';
            app.DirectoryButton.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.CompoundEditFieldLabel = uilabel(app.UIFigure);
            app.CompoundEditFieldLabel.Text = 'Compound ';
            app.CompoundEditField = uieditfield(app.UIFigure, 'text');
            app.prependstofilenamesLabel = uilabel(app.UIFigure);
            app.prependstofilenamesLabel.Text = '(pre-pends to file names)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rpr(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end