classdef Peak_Tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        PeakTrackerLabel              matlab.ui.control.Label
        DisplayPanel                  matlab.ui.container.Panel
        SeparatePlotButton            matlab.ui.control.Button
        LegendCheckBox                matlab.ui.control.CheckBox
        LabelPeaksCheckBox            matlab.ui.control.CheckBox
        TrackingSettingsPanel         matlab.ui.container.Panel
        IntervalSizeEditFieldLabel    matlab.ui.control.Label
        IntervalSizeEditField         matlab.ui.control.NumericEditField
        hzLabel_2                     matlab.ui.control.Label
        NextButton_2                  matlab.ui.control.Button
        PreviousButton_2              matlab.ui.control.Button
        FirstPeakIndexEditFieldLabel  matlab.ui.control.Label
        FirstPeakIndexEditField       matlab.ui.control.NumericEditField
        FrequencyToStartTrackingEditFieldLabel  matlab.ui.control.Label
        FrequencyToStartTrackingEditField  matlab.ui.control.NumericEditField
        hzLabel                       matlab.ui.control.Label
        FirstFilePeakCountEditFieldLabel  matlab.ui.control.Label
        FirstFilePeakCountEditField   matlab.ui.control.NumericEditField
        FileSelectionPanel            matlab.ui.container.Panel
        FileTree                      matlab.ui.container.Tree
        NextButton                    matlab.ui.control.Button
        PreviousButton                matlab.ui.control.Button
        MissingValuesPanel            matlab.ui.container.Panel
        MissingValuesCountEditFieldLabel  matlab.ui.control.Label
        MissingValuesCountEditField   matlab.ui.control.NumericEditField
        MissingTree                   matlab.ui.container.Tree
        MissingValuesNode             matlab.ui.container.TreeNode
    end


    properties (Access = private)
        
        parent_app % reference to calling application
        
        index      % index into tdms_data that 
                   % determines which file to plot.
                   
        recalculate_flag % boolean that determines whether or not 
                    % the tracked peaks should be recalculate_flagd
                    % prior to making another plot
                    
        plot_info   % struct for plot settings
        
        cell_intervals % intervals produced for searching
                       % for the next peak. (used for plotting)
                       
        file_node % folder node in the FileTree
        
        % first_peak_index = 0 % peak index to track 
                             % for the first file
        
    end

    methods %(Access = public)
    
        function Plot_Tracked(app,ax)
            
            % Clear axis
            cla(ax,'reset')
            
            % SET: fields for processing the data
            app.plot_info.preprocess = app.parent_app.tdms_data(app.index).plot_info.preprocess;                
            
            
            if app.recalculate_flag == true
               recalculate(app)
            end
            
            % PLOT: [magnitude data, magnitude peaks, tracked peak]
            Plot_File(ax,app.parent_app.tdms_data(app.index), app.plot_info);
            
            % PLOT: line at the originally tracked frequency
            y_lim=get(ax,'ylim');
            orig_freq = app.FrequencyToStartTrackingEditField.Value;
            
            plot(ax,                    ...
                [orig_freq, orig_freq], ...
                y_lim,                  ...
                'r-','DisplayName','original track frequency');
            
            % PLOT: interval
            mmin = app.cell_intervals{app.index}(1);
            mmax = app.cell_intervals{app.index}(2); 
            plot(ax,[mmin, mmin], y_lim, 'k', 'DisplayName', 'minimum peak bound');
            plot(ax,[mmax, mmax], y_lim, 'k', 'DisplayName', 'maximum peak bound');
            
            % ENABLE: fields in parent app
            app.parent_app.TrackedPeakCheckBox.Enable   = true;
            app.parent_app.AnalyzeTrackedButton.Enable  = true;
            
            % RESET: recalc
            app.recalculate_flag = false;  
            
        end
        
        
        function set.index(app, new_index)
            % SET: the new index
            app.index = new_index;
            
            % SET: current spot in filetree
            app.FileTree.SelectedNodes = app.file_node.Children(new_index);
            
        end
    end
    
    methods
        
        function plot_info = get.plot_info(app)
            plot_info = app.plot_info;
            plot_info.mag_labels = app.LabelPeaksCheckBox.Value;
            plot_info.legend = app.LegendCheckBox.Value;
        end
        
        function recalculate(app)
             % updates the GUI whenever the recalculate flag is set.
             
                % SET: tracked peaks / if recalc is set
                [peaks_tracked, app.cell_intervals] = Track_Peaks_Interval( ...
                                                        {app.parent_app.tdms_data.peaks}, ...
                                                        app.FrequencyToStartTrackingEditField.Value, ...
                                                        app.IntervalSizeEditField.Value);
                
                [app.parent_app.tdms_data.peaks_tracked] = peaks_tracked{:};
                
                % SET: missing values
                
                % delete current values in the tree
                delete(app.MissingValuesNode.Children)
                count_missing = 0;
                
                
                for i = 1:length(app.parent_app.tdms_data)
                    
                    if isempty(app.parent_app.tdms_data(i).peaks_tracked)
                        
                        % set new missing tree nodes
                        uitreenode(app.MissingValuesNode,'Text',num2str(app.parent_app.tdms_data(i).id));
                        count_missing = count_missing + 1;
                        
                        % remove icon in file tree
                        app.file_node.Children(i).Icon = '';
                        
                    else
                        
                        % set icon in file tree
                        app.file_node.Children(i).Icon = 'green_circle.jpeg';
                        
                    end
                
                end
                expand(app.MissingTree,'All');
                app.MissingValuesCountEditField.Value = count_missing;
                
                % SET: PeakCount
                td = app.parent_app.tdms_data(1);
                app.FirstFilePeakCountEditField.Value = length(td.peaks);

                
        end % END: recalcuate
        
    end % END: methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            
            % STORE: data
            app.parent_app = parent_app;
            
            % SET: plot_info
            p_info = Get_Plot_Struct();
            p_info.mag = 1;              % plot: magnitude
            p_info.peaks_mag = [1,0];    % plot: magnitude peaks
            p_info.peaks_tracked = 1;    % plot: tracked peak
            app.plot_info = p_info;
            
            % SET: Top Level File Nodes in FileTree
            app.file_node = uitreenode(app.FileTree, 'Text', 'Files');
            
            % SET: FileTree nodes
            for i = 1:length(app.parent_app.tdms_data)
                uitreenode(app.file_node, 'Text', app.parent_app.tdms_data(i).id);
            end
            expand(app.FileTree,'All');
            
            % SET: basic info
            app.index = 1;
            app.recalculate_flag = true;
            
            app.FrequencyToStartTrackingEditField.Value =  ...
                app.parent_app.tdms_data(1).plot_info.track_freq;
            
            app.IntervalSizeEditField.Value = ...
                app.parent_app.tdms_data(1).plot_info.track_interval;
            
            % Plot
            Plot_Tracked(app,app.UIAxes)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.index == length(app.parent_app.tdms_data)
                app.index = 1;
            else
                app.index = app.index + 1;
            end
           
            Plot_Tracked(app,app.UIAxes)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.index == 1
                app.index = length(app.parent_app.tdms_data);
            else
                app.index = app.index - 1;
            end
            
            Plot_Tracked(app, app.UIAxes)
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
            Plot_Tracked(app, gca);
        end

        % Value changed function: FrequencyToStartTrackingEditField
        function FrequencyToStartTrackingEditFieldValueChanged(app, event)
            value = app.FrequencyToStartTrackingEditField.Value;
            app.parent_app.tdms_data(1).plot_info.track_freq = value;
            app.recalculate_flag = true;
            app.index = 1;
            Plot_Tracked(app, app.UIAxes);
        end

        % Value changed function: IntervalSizeEditField
        function IntervalSizeEditFieldValueChanged(app, event)
            value = app.IntervalSizeEditField.Value;
            app.parent_app.tdms_data(1).plot_info.track_interval = value;
            app.recalculate_flag = true;
            app.index = 1;
            Plot_Tracked(app, app.UIAxes);
        end

        % Selection changed function: FileTree
        function FileTreeSelectionChanged(app, event)
            
            % GET: the text from the node that was selected
            text = app.FileTree.SelectedNodes.Text;
            
            % An error occurs when someone tries to pick a parent node
            % - I use this if statement as a fix towards that.
            app.index = find(string({app.parent_app.tdms_data.id}) == text);
            if ~isempty(app.index)
                Plot_Tracked(app, app.UIAxes)
            else
                disp('Could not plot the selected node ...')
            end
        end

        % Value changed function: LabelPeaksCheckBox, LegendCheckBox
        function Replot(app, event)
            Plot_Tracked(app, app.UIAxes)
        end

        % Value changed function: FirstPeakIndexEditField
        function FirstPeakIndexEditFieldValueChanged(app, event)
            
            % Don't do anything if there aren't any peaks
            % in the first file
            if isempty([app.parent_app.tdms_data(1).peaks])
                return
            end
        
            % selected peak to track
            peak_number = app.FirstPeakIndexEditField.Value;
            
            % GET: frequencies
            Frequencies = [app.parent_app.tdms_data(1).peaks.Frequencies];
            
            % actual peak to track
            [~, peak_number] = min(abs((1:length(Frequencies)) - peak_number));
            
            % set to the actual peak number
            app.FirstPeakIndexEditField.Value = peak_number;
            
            % set the frequency on the gui
            app.FrequencyToStartTrackingEditField.Value = Frequencies(peak_number);
            
            % set the frequency in the tdms_data object
            app.parent_app.tdms_data(1).plot_info.track_freq = Frequencies(peak_number);
            
            % recalculate the intervals
            app.recalculate_flag = true;
            
            % plot
            Plot_Tracked(app, app.UIAxes)
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            
            % Don't do anything if there aren't any peaks
            % in the first file
            if isempty([app.parent_app.tdms_data(1).peaks]) || length([app.parent_app.tdms_data(1).peaks]) == 1
                return
            end
            
            % increment the peak
            if app.FirstPeakIndexEditField.Value == app.FirstFilePeakCountEditField.Value
                app.FirstPeakIndexEditField.Value = 1;
            else
                app.FirstPeakIndexEditField.Value = app.FirstPeakIndexEditField.Value + 1;
            end
            
            % reprocess the GUI similar to if you had manually
            % changed the value by hand
            FirstPeakIndexEditFieldValueChanged(app);
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            % Don't do anything if there aren't any peaks
            % in the first file
            if isempty([app.parent_app.tdms_data(1).peaks]) || length([app.parent_app.tdms_data(1).peaks]) == 1
                return
            end
            
            % deccrement the peak
            if app.FirstPeakIndexEditField.Value == 1
                app.FirstPeakIndexEditField.Value = length([app.parent_app.tdms_data(1).peaks]);
            else
                app.FirstPeakIndexEditField.Value = app.FirstPeakIndexEditField.Value - 1;
            end
            
            % reprocess the GUI similar to if you had manually
            % changed the value by hand
            FirstPeakIndexEditFieldValueChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PeakTrackerLabel = uilabel(app.UIFigure);
            app.PeakTrackerLabel.Text = 'Peak Tracker';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.SeparatePlotButton = uibutton(app.DisplayPanel, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            app.LegendCheckBox = uicheckbox(app.DisplayPanel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @Replot, true);
            app.LegendCheckBox.Text = 'Legend';
            app.LabelPeaksCheckBox = uicheckbox(app.DisplayPanel);
            app.LabelPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @Replot, true);
            app.LabelPeaksCheckBox.Text = 'Label Peaks';
            app.LabelPeaksCheckBox.Value = true;
            app.TrackingSettingsPanel = uipanel(app.UIFigure);
            app.TrackingSettingsPanel.Title = 'Tracking Settings';
            app.IntervalSizeEditFieldLabel = uilabel(app.TrackingSettingsPanel);
            app.IntervalSizeEditFieldLabel.Text = 'Interval Size';
            app.IntervalSizeEditField = uieditfield(app.TrackingSettingsPanel, 'numeric');
            app.IntervalSizeEditField.Limits = [10 Inf];
            app.IntervalSizeEditField.ValueDisplayFormat = '%e';
            app.IntervalSizeEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalSizeEditFieldValueChanged, true);
            app.IntervalSizeEditField.Value = 15000;
            app.hzLabel_2 = uilabel(app.TrackingSettingsPanel);
            app.hzLabel_2.Text = 'hz';
            app.NextButton_2 = uibutton(app.TrackingSettingsPanel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.PreviousButton_2 = uibutton(app.TrackingSettingsPanel, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.FirstPeakIndexEditFieldLabel = uilabel(app.TrackingSettingsPanel);
            app.FirstPeakIndexEditFieldLabel.Text = 'First Peak Index';
            app.FirstPeakIndexEditField = uieditfield(app.TrackingSettingsPanel, 'numeric');
            app.FirstPeakIndexEditField.ValueChangedFcn = createCallbackFcn(app, @FirstPeakIndexEditFieldValueChanged, true);
            app.FrequencyToStartTrackingEditFieldLabel = uilabel(app.TrackingSettingsPanel);
            app.FrequencyToStartTrackingEditFieldLabel.Text = 'Frequency To Start Tracking';
            app.FrequencyToStartTrackingEditField = uieditfield(app.TrackingSettingsPanel, 'numeric');
            app.FrequencyToStartTrackingEditField.ValueDisplayFormat = '%e';
            app.FrequencyToStartTrackingEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyToStartTrackingEditFieldValueChanged, true);
            app.FrequencyToStartTrackingEditField.Value = 881200;
            app.hzLabel = uilabel(app.TrackingSettingsPanel);
            app.hzLabel.Text = 'hz';
            app.FirstFilePeakCountEditFieldLabel = uilabel(app.TrackingSettingsPanel);
            app.FirstFilePeakCountEditFieldLabel.Text = 'First File Peak Count';
            app.FirstFilePeakCountEditField = uieditfield(app.TrackingSettingsPanel, 'numeric');
            app.FirstFilePeakCountEditField.Editable = 'off';
            app.FileSelectionPanel = uipanel(app.UIFigure);
            app.FileSelectionPanel.Title = 'File Selection';
            app.FileTree = uitree(app.FileSelectionPanel);
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @FileTreeSelectionChanged, true);
            app.NextButton = uibutton(app.FileSelectionPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.FileSelectionPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.MissingValuesPanel = uipanel(app.UIFigure);
            app.MissingValuesPanel.Title = 'Missing Values';
            app.MissingValuesCountEditFieldLabel = uilabel(app.MissingValuesPanel);
            app.MissingValuesCountEditFieldLabel.Text = 'Missing Values Count';
            app.MissingValuesCountEditField = uieditfield(app.MissingValuesPanel, 'numeric');
            app.MissingValuesCountEditField.Editable = 'off';
            app.MissingTree = uitree(app.MissingValuesPanel);
            app.MissingValuesNode = uitreenode(app.MissingTree);
            app.MissingValuesNode.Text = 'Missing Values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peak_Tracker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end