classdef findpeaks_param < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        param_label_1                 matlab.ui.control.Label
        param_1                       matlab.ui.control.NumericEditField
        param_label_2                 matlab.ui.control.Label
        param_2                       matlab.ui.control.NumericEditField
        param_label_3                 matlab.ui.control.Label
        param_3                       matlab.ui.control.NumericEditField
        param_label_4                 matlab.ui.control.Label
        param_4                       matlab.ui.control.NumericEditField
        param_label_5                 matlab.ui.control.Label
        param_5                       matlab.ui.control.NumericEditField
        param_label_6                 matlab.ui.control.Label
        param_6                       matlab.ui.control.NumericEditField
        findpeaksparametersLabel      matlab.ui.control.Label
        findpeaksDocumentationButton  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        docButton_1                   matlab.ui.control.Button
        docButton_2                   matlab.ui.control.Button
        docButton_3                   matlab.ui.control.Button
        docButton_4                   matlab.ui.control.Button
        docButton_5                   matlab.ui.control.Button
        docButton_6                   matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        parent_app % The app that opened this app
        num_parameters % the number of parameters that are described by this app.
    end

    methods (Access = private)
    
        function Set_Fields(app)
            
            % ALIAS: for easier access to data
            index  = app.parent_app.index;
            
            % SET: fields in parent app
            for i = 1:app.num_parameters
                
                % GRAB: text from the fields
                text = app.(['param_label_',num2str(i)]).Text;
                
                % SET: the text in the parent app's index struct
                app.parent_app.tdms_data(index).plot_info.peak_options.(text) = ...
                    app.(['param_',num2str(i)]).Value;
            end
            
            % SET: the parent apps state, so that my programs will
            % no that it must be reprocessed.
            app.parent_app.Set_Recalc_All();
            
            % reprocess the file with the new information.
            cla(app.parent_app.UIAxes,'reset');
            app.parent_app.Reprocess(app.parent_app.UIAxes);
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            
            % SET: property
            
            % Refferece to the application that launched This App
            app.parent_app = parent_app;
            
            % Refference to the number of fields that may be set in
            % in the GUI. This is hard coded, the app fields were given
            % special names so that it is trivial to loop through them
            app.num_parameters = 6;
            
            % SET: inital parameter values
            peak_options = parent_app.tdms_data(parent_app.index).plot_info.peak_options;
            
            % loop through the different fields in the GUI and set there inital
            % values to what I have stored in the index struct
            for i = 1:app.num_parameters
                
                % STORE: the field label (not the set field)
                text = app.(['param_label_',num2str(i)]).Text;
                
                % GET: the value in index struct, using the field label
                b = peak_options.(text);
                
                % SET: the initial value for the fields based on what is in
                %      the index struct
                app.(['param_',num2str(i)]).Value = b;
            end
            
        end

        % Button pushed function: findpeaksDocumentationButton
        function findpeaksDocumentationButtonPushed(app, event)
            doc findpeaks
        end

        % Button pushed function: docButton_1
        function docButton_1Pushed(app, event)
            app.TextArea.Value = "Use this argument to have findpeaks return only those peaks higher than 'MinPeakHeight'. Specifying a minimum peak height can reduce processing time.";
        end

        % Button pushed function: docButton_2
        function docButton_2Pushed(app, event)
            app.TextArea.Value = "Use this argument to have findpeaks return only those peaks that have a relative importance of at least 'MinPeakProminence'.";
        end

        % Button pushed function: docButton_3
        function docButton_3Pushed(app, event)
            app.TextArea.Value = "Minimum height difference between a peak and its neighbors. Use this argument to have findpeaks return only those peaks that exceed their immediate neighboring values by at least the value of 'Threshold'.";
        end

        % Button pushed function: docButton_4
        function docButton_4Pushed(app, event)
            app.TextArea.Value = "Minimum peak separation. When you specify a value for 'MinPeakDistance', the algorithm chooses the tallest peak in the signal and ignores all peaks within 'MinPeakDistance' of it. The function then repeats the procedure for the tallest remaining peak and iterates until it runs out of peaks to consider.";
        end

        % Button pushed function: docButton_5
        function docButton_5Pushed(app, event)
           app.TextArea.Value = "Use this argument to select only those peaks that have widths of at least 'MinPeakWidth'.";
        end

        % Button pushed function: docButton_6
        function docButton_6Pushed(app, event)
           app.TextArea.Value = "Use this argument to select only those peaks that have widths of at most 'MaxPeakWidth'.";
        end

        % Value changed function: param_1, param_2, param_3, 
        % param_4, param_5, param_6
        function param_1ValueChanged(app, event)
           app.Set_Fields();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            figure(app.parent_app.UIFigure)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.param_label_1 = uilabel(app.UIFigure);
            app.param_label_1.Text = 'MinPeakHeight';
            app.param_1 = uieditfield(app.UIFigure, 'numeric');
            app.param_1.Limits = [0 Inf];
            app.param_1.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_label_2 = uilabel(app.UIFigure);
            app.param_label_2.Text = 'MinPeakProminence';
            app.param_2 = uieditfield(app.UIFigure, 'numeric');
            app.param_2.Limits = [0 Inf];
            app.param_2.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_label_3 = uilabel(app.UIFigure);
            app.param_label_3.Text = 'Threshold';
            app.param_3 = uieditfield(app.UIFigure, 'numeric');
            app.param_3.Limits = [0 Inf];
            app.param_3.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_label_4 = uilabel(app.UIFigure);
            app.param_label_4.Text = 'MinPeakDistance';
            app.param_4 = uieditfield(app.UIFigure, 'numeric');
            app.param_4.Limits = [0 Inf];
            app.param_4.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_label_5 = uilabel(app.UIFigure);
            app.param_label_5.Text = 'MinPeakWidth';
            app.param_5 = uieditfield(app.UIFigure, 'numeric');
            app.param_5.Limits = [0 Inf];
            app.param_5.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_label_6 = uilabel(app.UIFigure);
            app.param_label_6.Text = 'MaxPeakWidth';
            app.param_6 = uieditfield(app.UIFigure, 'numeric');
            app.param_6.Limits = [0 Inf];
            app.param_6.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.findpeaksparametersLabel = uilabel(app.UIFigure);
            app.findpeaksparametersLabel.Text = 'findpeaks parameters';
            app.findpeaksDocumentationButton = uibutton(app.UIFigure, 'push');
            app.findpeaksDocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @findpeaksDocumentationButtonPushed, true);
            app.findpeaksDocumentationButton.Text = 'findpeaks Documentation';
            app.TextArea = uitextarea(app.UIFigure);
            app.docButton_1 = uibutton(app.UIFigure, 'push');
            app.docButton_1.ButtonPushedFcn = createCallbackFcn(app, @docButton_1Pushed, true);
            app.docButton_1.Text = 'doc';
            app.docButton_2 = uibutton(app.UIFigure, 'push');
            app.docButton_2.ButtonPushedFcn = createCallbackFcn(app, @docButton_2Pushed, true);
            app.docButton_2.Text = 'doc';
            app.docButton_3 = uibutton(app.UIFigure, 'push');
            app.docButton_3.ButtonPushedFcn = createCallbackFcn(app, @docButton_3Pushed, true);
            app.docButton_3.Text = 'doc';
            app.docButton_4 = uibutton(app.UIFigure, 'push');
            app.docButton_4.ButtonPushedFcn = createCallbackFcn(app, @docButton_4Pushed, true);
            app.docButton_4.Text = 'doc';
            app.docButton_5 = uibutton(app.UIFigure, 'push');
            app.docButton_5.ButtonPushedFcn = createCallbackFcn(app, @docButton_5Pushed, true);
            app.docButton_5.Text = 'doc';
            app.docButton_6 = uibutton(app.UIFigure, 'push');
            app.docButton_6.ButtonPushedFcn = createCallbackFcn(app, @docButton_6Pushed, true);
            app.docButton_6.Text = 'doc';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = findpeaks_param(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end