classdef Data_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LorentzianPeakTrackerLabel   matlab.ui.control.Label
        Tree                         matlab.ui.container.Tree
        DirectoryEditFieldLabel      matlab.ui.control.Label
        DirectoryEditField           matlab.ui.control.EditField
        UseDirectoryButton           matlab.ui.control.Button
        FindDirectoryButton          matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        BusyProcessingLampLabel      matlab.ui.control.Label
        BusyProcessingLamp           matlab.ui.control.Lamp
        PlottingPanel                matlab.ui.container.Panel
        SubplotsButton               matlab.ui.control.Button
        ReprocessButton              matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
        SeperatePlotButton           matlab.ui.control.Button
        FollowPeaksCheckBox          matlab.ui.control.CheckBox
        PreviousButton               matlab.ui.control.Button
        FollowTrackedCheckBox        matlab.ui.control.CheckBox
        LegendCheckBox               matlab.ui.control.CheckBox
        SettingsPanel                matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        WhatToPlotTab                matlab.ui.container.Tab
        DataPanel                    matlab.ui.container.Panel
        RawDataCheckBox              matlab.ui.control.CheckBox
        MagnitudeDataCheckBox        matlab.ui.control.CheckBox
        PeaksPanel                   matlab.ui.container.Panel
        RawPeakCheckBox              matlab.ui.control.CheckBox
        MagnitudePeakCheckBox        matlab.ui.control.CheckBox
        TrackedPeakCheckBox          matlab.ui.control.CheckBox
        FitsPanel                    matlab.ui.container.Panel
        RawFitCheckBox               matlab.ui.control.CheckBox
        MagnitudeFitCheckBox         matlab.ui.control.CheckBox
        ProcessPanel                 matlab.ui.container.Panel
        DetrendCheckBox              matlab.ui.control.CheckBox
        SmoothCheckBox               matlab.ui.control.CheckBox
        PeakFindingTab               matlab.ui.container.Tab
        FittingTab                   matlab.ui.container.Tab
        ExecutionTimeEditFieldLabel  matlab.ui.control.Label
        ExecutionTimeEditField       matlab.ui.control.EditField
        sLabel                       matlab.ui.control.Label
        LastOperationEditFieldLabel  matlab.ui.control.Label
        LastOperationEditField       matlab.ui.control.EditField
        PeaksFoundEditFieldLabel     matlab.ui.control.Label
        PeaksFoundEditField          matlab.ui.control.NumericEditField
        ProcessAllFilesButton        matlab.ui.control.Button
        PeakTrackingPanel            matlab.ui.container.Panel
        TrackPeaksButton             matlab.ui.control.Button
        AnalyzeTrackedButton         matlab.ui.control.Button
        TempEditFieldLabel           matlab.ui.control.Label
        TempEditField                matlab.ui.control.NumericEditField
        SetTempButton                matlab.ui.control.Button
        KLabel                       matlab.ui.control.Label
    end


    properties (Access = private)

        index     % index used to refference the current file.
        
        dir_node  % directory node in tree.
        
        recalculate % boolean that tells the program to recalculate the fit and peak
                    % data prior to performing a plot.
                    
    end
    
    properties (Access = public)
        tdms_data % stores all of the data.
    end

    methods (Access = private)
    
        function Update_Plot_Info(app)
            % SET: fields in plot_info struct based on the settings 
            %      in the GUI.
            
            % GET: plot_info struct
            plot_info = Get_Plot_Struct();
            
            % Plot - Data
            if app.RawDataCheckBox.Value
                plot_info.raw = 1;
            end
            if app.MagnitudeDataCheckBox.Value
               plot_info.mag = 1; 
            end
            
            % Plot - Peaks
            if app.RawPeakCheckBox.Value
               plot_info.peaks_raw_set(1) = 1; 
            end
            if app.MagnitudePeakCheckBox.Value
               plot_info.peaks_mag_set(1) = 1; 
            end
            if app.TrackedPeakCheckBox.Value
               plot_info.peaks_tracked = 1; 
            end
            
            % Plot - Fits
            if app.RawFitCheckBox.Value
               plot_info.peaks_raw_set(2) = 1;
            end
            if app.MagnitudeFitCheckBox.Value
               plot_info.peaks_mag_set(2) = 1; 
            end
            
            % Plot - Process
            if app.SmoothCheckBox.Value 
               plot_info.preprocess{end+1}  = @Smooth; 
            end
            if app.DetrendCheckBox.Value
                plot_info.preprocess{end+1} = @Detrend;
            end
            
            % SET plot_info
            [app.tdms_data.plot_info] = deal(plot_info);
            
        end
    
        
        function Reprocess(app,ax)
            
            tic
            %app.BusyProcessingLamp.Enable = 'on';
            %drawnow % refresh
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % SET: fields from GUI, and process the data
            if app.recalculate(app.index)
                Update_Plot_Info(app);
                app.tdms_data(app.index) = Process_Data(app.tdms_data(app.index));
                app.LastOperationEditField.Value = ['Processed File With Temp: ', ...
                        num2str(app.tdms_data(app.index).temperature),'K'];
            else
                app.LastOperationEditField.Value = ['Plotted File With Temp: ', ...
                        num2str(app.tdms_data(app.index).temperature),'K'];
            end
            
            % PLOT:
            Plot_File(ax,app.tdms_data(app.index))
            
            % SET: axis 
            title(ax,['Temperature: ',num2str(app.tdms_data(app.index).temperature),'K']);
            xlabel(ax,'Frequency (khz)');
            ylabel(ax,'Voltage (V)');
            
            if app.LegendCheckBox.Value
               legend(ax); 
            end
            
            % RESET (so as not to have to continually recalculate fits)
            app.recalculate(app.index) = false;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % SET: tree icon
            app.dir_node.Children(app.index).Icon = 'green_circle.jpeg';
            app.Tree.SelectedNodes = app.dir_node.Children(app.index);
            
            % Save: Energy
            %app.BusyProcessingLamp.Enable = 'off';
            
            % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc);
            
            % SET: # Peaks Found
            app.PeaksFoundEditField.Value = length(app.tdms_data(app.index).peaks_mag_set);
            
            
        end
        
        function Set_Recalc_All(app)
            if sum(app.recalculate) ~= length(app.tdms_data)
                app.recalculate = true(1,length(app.tdms_data));
                Children = app.dir_node.Children;
                [Children.Icon] = deal('');
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.index = 1;
        end

        % Button pushed function: FindDirectoryButton
        function FindDirectoryButtonPushed(app, event)
            
            % GUI: dir finder 
            selpath = uigetdir('../../2-Files_To_Analyze/');
            
            % SET: dir field 
            app.DirectoryEditField.Value = selpath;
            
            % SET: dir node
            [~,dir,~] = fileparts(selpath);
            app.dir_node = uitreenode(app.Tree,'Text',dir);
            
        end

        % Button pushed function: UseDirectoryButton
        function UseDirectoryButtonPushed(app, event)
            
            tic
            app.BusyProcessingLamp.Enable = 'on';
            drawnow % refresh
            
            % SET: data from tdms files in the given directory
            app.tdms_data = TDMS_Load(app.DirectoryEditField.Value);
            
            % SET: File Nodes in Tree
            for i = 1:length(app.tdms_data)
                uitreenode(app.dir_node,'Text',num2str(app.tdms_data(i).temperature));
            end
            expand(app.dir_node);
            
            % GET: Set Plot Info Fields
            plot_info = Get_Plot_Struct();
            
            % SET: plot info / recalculate
            [app.tdms_data.plot_info] = deal(plot_info);
            app.recalculate = true(1,length(app.tdms_data));
            
            app.BusyProcessingLamp.Enable = 'off';
            
            % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc);
            
            % SET: last process ran
            app.LastOperationEditField.Value = 'Read In Data';
            
            % Generate The First Plot
            ReprocessButtonPushed(app);
            
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            Reprocess(app,app.UIAxes);
        end

        % Value changed function: RawDataCheckBox
        function RawDataCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: MagnitudeDataCheckBox
        function MagnitudeDataCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: RawPeakCheckBox
        function RawPeakCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: MagnitudePeakCheckBox
        function MagnitudePeakCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: TrackedPeakCheckBox
        function TrackedPeakCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: RawFitCheckBox
        function RawFitCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: MagnitudeFitCheckBox
        function MagnitudeFitCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: DetrendCheckBox
        function DetrendCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            Set_Recalc_All(app);
            ReprocessButtonPushed(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.index == length(app.tdms_data)
                app.index = 1;
            else
                app.index = app.index + 1;
            end
            ReprocessButtonPushed(app);
        end

        % Button pushed function: SeperatePlotButton
        function SeperatePlotButtonPushed(app, event)
            figure();
            Reprocess(app,gca);
        end

        % Value changed function: FollowPeaksCheckBox
        function FollowPeaksCheckBoxValueChanged(app, event)
            
            
        end

        % Button pushed function: SubplotsButton
        function SubplotsButtonPushed(app, event)
            
            % SET: busy
            tic
            app.BusyProcessingLamp.Enable = 'on';
            drawnow % refresh
            
            % process and plot
            ProcessAllFilesButtonPushed(app);
            Sub_Plots(app.tdms_data);
            
            % SET: busy
            app.BusyProcessingLamp.Enable = 'off';
            
            % SET: last process ran
            app.LastOperationEditField.Value = 'Read In Data';
            
            % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.index == 1
                app.index = length(app.tdms_data);
            else
                app.index = app.index - 1;
            end
            ReprocessButtonPushed(app);
        end

        % Button pushed function: ProcessAllFilesButton
        function ProcessAllFilesButtonPushed(app, event)
            
            tic
            app.BusyProcessingLamp.Enable = 'on';
            drawnow % refresh
            
            % UPDATE: info used for processing data
            Update_Plot_Info(app);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            num_files_to_recalc = num2str(sum(app.recalculate));
            for i = 1:length(app.recalculate)
            
                % SET: fields from GUI, and process the data
                if app.recalculate(i)
                    
                    % SET: processed data
                    app.tdms_data(i) =  Process_Data(app.tdms_data(i));
                    
                    % SET: tree icon
                    app.dir_node.Children(i).Icon = 'green_circle.jpeg';
                    app.Tree.SelectedNodes = app.dir_node.Children(app.index);
                    drawnow % refresh
                    
                    % SET: recalculate boolean
                    app.recalculate(i) = false;
                    
                end
                
            end    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % SET: last operation
            app.LastOperationEditField.Value =  ...
                ['Processed ',num_files_to_recalc,' Files'];
                    
            % Save: Energy
            app.BusyProcessingLamp.Enable = 'off';
            
             % SET: exetution time field
            app.ExecutionTimeEditField.Value = num2str(toc);
        end

        % Button pushed function: TrackPeaksButton
        function TrackPeaksButtonPushed(app, event)
            ProcessAllFilesButtonPushed(app);
            Peak_Tracker(app);
        end

        % Button pushed function: AnalyzeTrackedButton
        function AnalyzeTrackedButtonPushed(app, event)
            Peak_Analyzer(app.tdms_data);
        end

        % Button pushed function: SetTempButton
        function SetTempButtonPushed(app, event)
            
            % GET: temperatures
            given_temp = app.TempEditField.Value;
            all_temps = [app.tdms_data.temperature];
            
            % FIND: file index corresponding to the 
            %       closest temperature in my data to the given temperature
            new_index = 1;
            old_diff = abs(all_temps(1)-given_temp);
            for i = 2:length(all_temps)
                new_diff = abs(all_temps(i)-given_temp);
                if new_diff > old_diff 
                    new_index = i-1;
                    break
                elseif i == length(all_temps)
                    new_index = length(all_temps);
                end
                old_diff = new_diff;
            end
            
            % SET: temperature
            app.TempEditField.Value = app.tdms_data(new_index).temperature;
            
            % SET: index
            app.index = new_index;
            
            % REPLOT
            ReprocessButtonPushed(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LorentzianPeakTrackerLabel = uilabel(app.UIFigure);
            app.LorentzianPeakTrackerLabel.Text = 'Lorentzian Peak Tracker';
            app.Tree = uitree(app.UIFigure);
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.UseDirectoryButton = uibutton(app.UIFigure, 'push');
            app.UseDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @UseDirectoryButtonPushed, true);
            app.UseDirectoryButton.Text = 'Use Directory';
            app.FindDirectoryButton = uibutton(app.UIFigure, 'push');
            app.FindDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @FindDirectoryButtonPushed, true);
            app.FindDirectoryButton.Icon = 'yellow-folder.png';
            app.FindDirectoryButton.Text = 'Find Directory';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BusyProcessingLampLabel = uilabel(app.UIFigure);
            app.BusyProcessingLampLabel.Text = 'Busy Processing';
            app.BusyProcessingLamp = uilamp(app.UIFigure);
            app.BusyProcessingLamp.Enable = 'off';
            app.PlottingPanel = uipanel(app.UIFigure);
            app.PlottingPanel.Title = 'Plotting';
            app.SubplotsButton = uibutton(app.PlottingPanel, 'push');
            app.SubplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SubplotsButtonPushed, true);
            app.SubplotsButton.Icon = 'grid.png';
            app.SubplotsButton.Text = 'Subplots';
            app.ReprocessButton = uibutton(app.PlottingPanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Icon = 'reload.png';
            app.ReprocessButton.Text = 'Re-process';
            app.NextButton = uibutton(app.PlottingPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = 'right_arrow.png';
            app.NextButton.Text = 'Next';
            app.SeperatePlotButton = uibutton(app.PlottingPanel, 'push');
            app.SeperatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeperatePlotButtonPushed, true);
            app.SeperatePlotButton.Icon = 'sepperate.png';
            app.SeperatePlotButton.Text = 'Seperate Plot';
            app.FollowPeaksCheckBox = uicheckbox(app.PlottingPanel);
            app.FollowPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @FollowPeaksCheckBoxValueChanged, true);
            app.FollowPeaksCheckBox.Text = 'Follow Peaks';
            app.PreviousButton = uibutton(app.PlottingPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Icon = 'left_arrow.png';
            app.PreviousButton.Text = 'Previous';
            app.FollowTrackedCheckBox = uicheckbox(app.PlottingPanel);
            app.FollowTrackedCheckBox.Enable = 'off';
            app.FollowTrackedCheckBox.Text = 'Follow Tracked';
            app.LegendCheckBox = uicheckbox(app.PlottingPanel);
            app.LegendCheckBox.Text = 'Legend';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.TabGroup = uitabgroup(app.SettingsPanel);
            app.WhatToPlotTab = uitab(app.TabGroup);
            app.WhatToPlotTab.Title = 'What To Plot';
            app.DataPanel = uipanel(app.WhatToPlotTab);
            app.DataPanel.Title = 'Data';
            app.RawDataCheckBox = uicheckbox(app.DataPanel);
            app.RawDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawDataCheckBoxValueChanged, true);
            app.RawDataCheckBox.Text = 'Raw';
            app.RawDataCheckBox.Value = true;
            app.MagnitudeDataCheckBox = uicheckbox(app.DataPanel);
            app.MagnitudeDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagnitudeDataCheckBoxValueChanged, true);
            app.MagnitudeDataCheckBox.Text = 'Magnitude';
            app.PeaksPanel = uipanel(app.WhatToPlotTab);
            app.PeaksPanel.Title = 'Peaks';
            app.RawPeakCheckBox = uicheckbox(app.PeaksPanel);
            app.RawPeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawPeakCheckBoxValueChanged, true);
            app.RawPeakCheckBox.Text = 'Raw';
            app.MagnitudePeakCheckBox = uicheckbox(app.PeaksPanel);
            app.MagnitudePeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagnitudePeakCheckBoxValueChanged, true);
            app.MagnitudePeakCheckBox.Text = 'Magnitude';
            app.TrackedPeakCheckBox = uicheckbox(app.PeaksPanel);
            app.TrackedPeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackedPeakCheckBoxValueChanged, true);
            app.TrackedPeakCheckBox.Enable = 'off';
            app.TrackedPeakCheckBox.Text = 'Tracked';
            app.FitsPanel = uipanel(app.WhatToPlotTab);
            app.FitsPanel.Title = 'Fits';
            app.RawFitCheckBox = uicheckbox(app.FitsPanel);
            app.RawFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawFitCheckBoxValueChanged, true);
            app.RawFitCheckBox.Text = 'Raw';
            app.RawFitCheckBox.Value = true;
            app.MagnitudeFitCheckBox = uicheckbox(app.FitsPanel);
            app.MagnitudeFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagnitudeFitCheckBoxValueChanged, true);
            app.MagnitudeFitCheckBox.Text = 'Magnitude';
            app.ProcessPanel = uipanel(app.WhatToPlotTab);
            app.ProcessPanel.Title = 'Process';
            app.DetrendCheckBox = uicheckbox(app.ProcessPanel);
            app.DetrendCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetrendCheckBoxValueChanged, true);
            app.DetrendCheckBox.Text = 'Detrend';
            app.DetrendCheckBox.Value = true;
            app.SmoothCheckBox = uicheckbox(app.ProcessPanel);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Text = 'Smooth';
            app.SmoothCheckBox.Value = true;
            app.PeakFindingTab = uitab(app.TabGroup);
            app.PeakFindingTab.Title = 'Peak Finding';
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.ExecutionTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExecutionTimeEditFieldLabel.Text = 'Execution Time';
            app.ExecutionTimeEditField = uieditfield(app.UIFigure, 'text');
            app.ExecutionTimeEditField.Editable = 'off';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.LastOperationEditFieldLabel = uilabel(app.UIFigure);
            app.LastOperationEditFieldLabel.Text = 'Last Operation';
            app.LastOperationEditField = uieditfield(app.UIFigure, 'text');
            app.LastOperationEditField.Editable = 'off';
            app.PeaksFoundEditFieldLabel = uilabel(app.UIFigure);
            app.PeaksFoundEditFieldLabel.Text = 'Peaks Found';
            app.PeaksFoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcessAllFilesButton = uibutton(app.UIFigure, 'push');
            app.ProcessAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllFilesButtonPushed, true);
            app.ProcessAllFilesButton.Text = 'Process All Files';
            app.PeakTrackingPanel = uipanel(app.UIFigure);
            app.PeakTrackingPanel.Title = 'Peak Tracking';
            app.TrackPeaksButton = uibutton(app.PeakTrackingPanel, 'push');
            app.TrackPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @TrackPeaksButtonPushed, true);
            app.TrackPeaksButton.Icon = 'track.png';
            app.TrackPeaksButton.Text = 'Track Peaks';
            app.AnalyzeTrackedButton = uibutton(app.PeakTrackingPanel, 'push');
            app.AnalyzeTrackedButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeTrackedButtonPushed, true);
            app.AnalyzeTrackedButton.Enable = 'off';
            app.AnalyzeTrackedButton.Text = 'Analyze Tracked';
            app.TempEditFieldLabel = uilabel(app.UIFigure);
            app.TempEditFieldLabel.Text = 'Temp';
            app.TempEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetTempButton = uibutton(app.UIFigure, 'push');
            app.SetTempButton.ButtonPushedFcn = createCallbackFcn(app, @SetTempButtonPushed, true);
            app.SetTempButton.Text = 'Set Temp';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Data_Analyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end