classdef Peak_Tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        PeakTrackerLabel            matlab.ui.control.Label
        PickTemperatureToGraphEditFieldLabel  matlab.ui.control.Label
        PickTemperatureToGraphEditField  matlab.ui.control.NumericEditField
        NextButton                  matlab.ui.control.Button
        PreviousButton              matlab.ui.control.Button
        DisplayPickedTempButton     matlab.ui.control.Button
        SeparatePlotButton          matlab.ui.control.Button
        KLabel                      matlab.ui.control.Label
        PeaksToTrackButtonGroup     matlab.ui.container.ButtonGroup
        GivenButton                 matlab.ui.control.ToggleButton
        SetButton                   matlab.ui.control.ToggleButton
        SubplotsButton              matlab.ui.control.Button
        FrequencyToStartTrackingEditFieldLabel  matlab.ui.control.Label
        FrequencyToStartTrackingEditField  matlab.ui.control.NumericEditField
        khzLabel                    matlab.ui.control.Label
        IntervalSizeEditFieldLabel  matlab.ui.control.Label
        IntervalSizeEditField       matlab.ui.control.NumericEditField
        clearButton                 matlab.ui.control.Button
        ToggleLegendButton          matlab.ui.control.StateButton
    end


    properties (Access = private)
        %tdms_data  % all of the data used to make plots
        parent_app
        
        index      % index into tdms_data that 
                   % determines which file to plot.
                   
        recalculate % boolean that determines whether or not 
                    % the tracked peaks should be recalculated
                    % prior to making another plot
                    
        plot_info   % default plot settings
        
        cell_intervals % intervals produced for searching
                       % for the next peak. (used for plotting)
        
    end

    methods (Access = private)
    
        function Plot_Tracked(app,ax)
            
            % GET: type of peak to start tracking
            % - set plot_info accordingly
            cla(ax,'reset')
            
            peak_lbl = [];
            app.plot_info.preprocess = app.parent_app.tdms_data(app.index).plot_info.preprocess;
            if app.GivenButton.Value == true
                peak_lbl = 'peaks_mag_given';
                app.plot_info.peaks_mag_given = [1,0];
                app.plot_info.peaks_mag_set = [0,0];
            else
                peak_lbl = 'peaks_mag_set';
                app.plot_info.peaks_mag_given = [0,0];
                app.plot_info.peaks_mag_set = [1,0];                
            end
            
            % SET: tracked peaks / if recalc is set
            if app.recalculate == true
                [peaks_tracked,app.cell_intervals] = Track_Peaks_Interval({app.parent_app.tdms_data.(peak_lbl)}, ...
                                                        app.FrequencyToStartTrackingEditField.Value, ...
                                                        app.IntervalSizeEditField.Value,  ...
                                                        'height', ...
                                                        false);
                
                [app.parent_app.tdms_data.peaks_tracked] = peaks_tracked{:};
            end
            
            % PLOT:
            Plot_File(ax,app.parent_app.tdms_data(app.index), app.plot_info);
            
            %%%%
            % easier accesss to data
            %peak_tracked = app.parent_app.tdms_data(app.index).peaks_tracked;
            %peak_freq = peak_tracked.Frequencies;
            mmin = app.cell_intervals{app.index}(1);
            mmax = app.cell_intervals{app.index}(2); 
            orig_freq = app.FrequencyToStartTrackingEditField.Value;
            
            % Plot: original peak
            y_lim=get(ax,'ylim');
            plot(ax, ...
                [orig_freq, orig_freq], ...
                y_lim, ...
                'r-','DisplayName','original track frequency');
            
            % Plot: interval
            plot(ax,[mmin, mmin],y_lim,'k','DisplayName','minimum peak bound');
            plot(ax,[mmax, mmax],y_lim,'k','DisplayName','maximum peak bound');
            
            % SET axes:
            title(ax, ['Temperature ', ...
                              num2str(app.parent_app.tdms_data(app.index).temperature)])
            ylabel(ax, "Voltage (V)")
            xlabel(ax, "Frequency (khz)")
            
            % RESET: recalc
            app.recalculate = false;  
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            
            % STORE: data
            %app.parent_app.tdms_data  = parent_apptdms_data_all;
            app.parent_app = parent_app;
            
            % SET: basic info
            app.index = 1;
            app.recalculate = true;
            
            % SET: plot_info
            p_info = Get_Plot_Struct();
            p_info.mag = 1;
            p_info.peaks_tracked = 1;
            %p_info.peaks_tracked = 1;
            app.plot_info = p_info;
            
            % SET: Fields
            app.PickTemperatureToGraphEditField.Value = ...
                app.parent_app.tdms_data(1).temperature;
            app.FrequencyToStartTrackingEditField.Value =  ...
                app.parent_app.tdms_data(1).plot_info.track_freq;
            app.IntervalSizeEditField.Value = ...
                app.parent_app.tdms_data(1).plot_info.track_interval;
            
        end

        % Button pushed function: DisplayPickedTempButton
        function DisplayPickedTempButtonPushed(app, event)
           
            % GET: temperature
            plot_temp = app.PickTemperatureToGraphEditField.Value;
            td = app.parent_app.tdms_data;
            all_temp  = [td.temperature];
            
            % GET: Index
            tf1 = all_temp == plot_temp;
            % skip if the temp does not match a temp for one of the files.
            if isempty(tf1)
               return 
            end
            app.index = find(tf1);
            
            % PLOT
            Plot_Tracked(app,app.UIAxes);
            
            % ENABLE: fields in parent app
            app.parent_app.TrackedPeakCheckBox.Enable   = true;
            app.parent_app.AnalyzeTrackedButton.Enable  = true;
            app.parent_app.FollowTrackedCheckBox.Enable = true;
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.index == length(app.parent_app.tdms_data)
                app.index = 1;
            else
                app.index = app.index + 1;
            end
            Plot_Tracked(app,app.UIAxes)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.index == 1
                app.index = length(app.parent_app.tdms_data);
            else
                app.index = app.index - 1;
            end
            Plot_Tracked(app,app.UIAxes)
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            cla(app.UIAxes,'reset')
        end

        % Button pushed function: SeparatePlotButton
        function SeparatePlotButtonPushed(app, event)
            Plot_Tracked(app, gca);
        end

        % Value changed function: ToggleLegendButton
        function ToggleLegendButtonValueChanged(app, event)
            value = app.ToggleLegendButton.Value;
            legend(app.UIAxes,'toggle');
        end

        % Value changed function: FrequencyToStartTrackingEditField
        function FrequencyToStartTrackingEditFieldValueChanged(app, event)
            value = app.FrequencyToStartTrackingEditField.Value;
            app.recalculate = true;
        end

        % Value changed function: IntervalSizeEditField
        function IntervalSizeEditFieldValueChanged(app, event)
            value = app.IntervalSizeEditField.Value;
            app.recalculate = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PeakTrackerLabel = uilabel(app.UIFigure);
            app.PeakTrackerLabel.Text = 'Peak Tracker';
            app.PickTemperatureToGraphEditFieldLabel = uilabel(app.UIFigure);
            app.PickTemperatureToGraphEditFieldLabel.Text = 'Pick Temperature To Graph';
            app.PickTemperatureToGraphEditField = uieditfield(app.UIFigure, 'numeric');
            app.PickTemperatureToGraphEditField.Limits = [0 Inf];
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.DisplayPickedTempButton = uibutton(app.UIFigure, 'push');
            app.DisplayPickedTempButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPickedTempButtonPushed, true);
            app.DisplayPickedTempButton.Text = 'Display Picked Temp';
            app.SeparatePlotButton = uibutton(app.UIFigure, 'push');
            app.SeparatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeparatePlotButtonPushed, true);
            app.SeparatePlotButton.Text = 'Separate Plot';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.PeaksToTrackButtonGroup = uibuttongroup(app.UIFigure);
            app.PeaksToTrackButtonGroup.Title = 'Peaks To Track';
            app.GivenButton = uitogglebutton(app.PeaksToTrackButtonGroup);
            app.GivenButton.Text = 'Given';
            app.SetButton = uitogglebutton(app.PeaksToTrackButtonGroup);
            app.SetButton.Text = 'Set';
            app.SetButton.Value = true;
            app.SubplotsButton = uibutton(app.UIFigure, 'push');
            app.SubplotsButton.Text = 'Subplots';
            app.FrequencyToStartTrackingEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyToStartTrackingEditFieldLabel.Text = 'Frequency To Start Tracking';
            app.FrequencyToStartTrackingEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyToStartTrackingEditField.ValueDisplayFormat = '%e';
            app.FrequencyToStartTrackingEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyToStartTrackingEditFieldValueChanged, true);
            app.FrequencyToStartTrackingEditField.Value = 881200;
            app.khzLabel = uilabel(app.UIFigure);
            app.khzLabel.Text = 'khz';
            app.IntervalSizeEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalSizeEditFieldLabel.Text = 'Interval Size';
            app.IntervalSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalSizeEditField.Limits = [10 Inf];
            app.IntervalSizeEditField.ValueDisplayFormat = '%e';
            app.IntervalSizeEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalSizeEditFieldValueChanged, true);
            app.IntervalSizeEditField.Value = 15000;
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.ToggleLegendButton = uibutton(app.UIFigure, 'state');
            app.ToggleLegendButton.ValueChangedFcn = createCallbackFcn(app, @ToggleLegendButtonValueChanged, true);
            app.ToggleLegendButton.Text = 'Toggle Legend';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Peak_Tracker(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end