classdef Export_Data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ExportDataAsmatLabel     matlab.ui.control.Label
        FileNameWithoutExtensionEditFieldLabel  matlab.ui.control.Label
        FileNameWithoutExtensionEditField  matlab.ui.control.EditField
        ExportButton             matlab.ui.control.Button
        DirectoryEditFieldLabel  matlab.ui.control.Label
        DirectoryEditField       matlab.ui.control.EditField
        FindDirectoryButton      matlab.ui.control.Button
    end


    properties (Access = public)
        parent_app % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
        end

        % Button pushed function: FindDirectoryButton
        function FindDirectoryButtonPushed(app, event)
            % GUI: dir finder 
            selpath = uigetdir();

            % Brings the main figure back into view
            figure(app.UIFigure);
            
            % EXIT: this function early if someone pressed cancel
            if selpath == 0
               return
            end
            
            % SET: dir field 
            app.DirectoryEditField.Value = selpath;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            % STORE: GUI fields
            file_name = app.FileNameWithoutExtensionEditField.Value;
            directory = app.DirectoryEditField.Value;
            
            % EXIT: early if one of the fields are not set.
            if isempty(file_name)
                f = errordlg('You need to specify a filename!','File Name Error');
                return
            end
            
            if isempty(directory)
                errordlg('You need to specify a Directory!','Directory Name Error');
                return
            end
            
            if ~exist(directory, 'dir')
                errordlg('The directory you specified could not be found!','Directory Name Error');
                return
            end
            
            % add a forward slash if it does not exist
            if directory(end) ~= '/'
                directory = [directory,'/'];
            end
                
            % create fill path file name to save .mat file to
            save_file = [directory,file_name,'.mat']; 
            
            % get the data that will be stored
            analyzed_tdms_data = app.parent_app.tdms_data;
            
            % STORE: the processed signal
            for i=1:length(app.parent_app.tdms_data)
                
                % ALIAS: for easier access to data
                info      = app.parent_app.tdms_data(i).plot_info;
        
                % preprocess the data
                for j = 1:length(info.preprocess)
                    
                    func = info.preprocess{j};
                    
                    app.parent_app.tdms_data(i).signal_x = ...
                        func(app.parent_app.tdms_data(i).signal_x);
                    app.parent_app.tdms_data(i).signal_y = ...
                        func(app.parent_app.tdms_data(i).signal_y);
                    
                end
        
            end
            
            % store the data in a .mat file
            save(save_file,'analyzed_tdms_data')
            
            % bring the focus back to the parent figure
            figure(app.parent_app.UIFigure)
            
            % close the export data app
            app.delete
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ExportDataAsmatLabel = uilabel(app.UIFigure);
            app.ExportDataAsmatLabel.Text = 'Export Data As .mat';
            app.FileNameWithoutExtensionEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameWithoutExtensionEditFieldLabel.Text = 'File Name (Without Extension)';
            app.FileNameWithoutExtensionEditField = uieditfield(app.UIFigure, 'text');
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.FindDirectoryButton = uibutton(app.UIFigure, 'push');
            app.FindDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @FindDirectoryButtonPushed, true);
            app.FindDirectoryButton.Icon = 'yellow-folder.png';
            app.FindDirectoryButton.Text = 'Find Directory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Export_Data(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end