classdef peakfit_param < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        lsqcurvefitParametersLabel      matlab.ui.control.Label
        param_1                         matlab.ui.control.NumericEditField
        param_2                         matlab.ui.control.NumericEditField
        param_3                         matlab.ui.control.NumericEditField
        param_4                         matlab.ui.control.NumericEditField
        docButton_1                     matlab.ui.control.Button
        docButton_2                     matlab.ui.control.Button
        docButton_3                     matlab.ui.control.Button
        docButton_4                     matlab.ui.control.Button
        lsqcurvefitdocumentationButton  matlab.ui.control.Button
        closeButton                     matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        param_5                         matlab.ui.control.NumericEditField
        docButton_5                     matlab.ui.control.Button
        param_label_1                   matlab.ui.control.Label
        param_label_2                   matlab.ui.control.Label
        param_label_3                   matlab.ui.control.Label
        param_label_4                   matlab.ui.control.Label
        param_label_5                   matlab.ui.control.Label
        GeneralFittingParametersLabel   matlab.ui.control.Label
        UseFitWidthMultiplierSwitchLabel  matlab.ui.control.Label
        UseFitWidthMultiplierSwitch     matlab.ui.control.Switch
        param_6                         matlab.ui.control.NumericEditField
        param_label_6                   matlab.ui.control.Label
        docButton_6                     matlab.ui.control.Button
    end


    properties (Access = public)
        parent_app % refference to the application that opened this one
        num_parameters % number of parameters that may be set
        hard_coded_width = 0;
    end

    methods (Access = private)
    
        function Set_Fields(app)
            
            % SET: parent apps hard coded boolean
            app.parent_app.tdms_data(app.parent_app.index).plot_info.hard_coded_width = app.hard_coded_width;
            
            % SET: fields in parent app
            for i = 1:app.num_parameters
                
                % GRAB: text from the fields
                text = app.(['param_label_',num2str(i)]).Text;
                
                % fields after 4 are stored elsewhere in the struct
                if i > 4
                    app.parent_app.tdms_data(app.parent_app.index).plot_info.(text) = ...
                    app.(['param_',num2str(i)]).Value;
                else
                    % SET: the text in the parent app's index struct
                    app.parent_app.tdms_data(app.parent_app.index).plot_info.fit_options.(text) = ...
                    app.(['param_',num2str(i)]).Value;
                end
               
            end
            
            % SET's: the parent apps state, so that my programs will
            % no that it must be reprocessed.
            app.parent_app.Set_Recalc_All();
            
            % Clear the current plot
            % cla(parent.UIAxes,'reset');
            
            % reprocess the file with the new information.
            cla(app.parent_app.UIAxes,'reset');
            app.parent_app.Reprocess(app.parent_app.UIAxes);
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            % This function
            % - SETS: inital parameter values
            
            % SET: fields
            app.parent_app = parent_app;
            app.num_parameters = 6;
            
            % alias for easier access to data
            fit_options = parent_app.tdms_data(parent_app.index).plot_info.fit_options;
            
            % loop through the different fields in the GUI and set there inital
            % values to what I have stored in the index struct
            for i = 1:app.num_parameters
                
                % STORE: the field label (not the set field)
                text = app.(['param_label_',num2str(i)]).Text;
               
                % fields after 4 are stored elsewhere in the struct
                if i > 4
                    fit_options = parent_app.tdms_data(parent_app.index).plot_info;
                end
                
                % GET: the value in index struct, using the field label
                default_val = fit_options.(text);
                
                % SET: the initial value for the fields based on what is in
                %      the index struct
                app.(['param_',num2str(i)]).Value = default_val;
            end
            
            % SET: state of the editable width parameter fields 
            %      so that only one can be accessed
            hard_coded_bool = app.parent_app.tdms_data(app.parent_app.index).plot_info.hard_coded_width;
            if hard_coded_bool == 1
                app.param_6.Enable = "On";
                app.param_5.Enable = "Off";
                app.UseFitWidthMultiplierSwitch.Value = "Off";
            else
                app.param_6.Enable = "Off";
                app.param_5.Enable = "On";
                app.UseFitWidthMultiplierSwitch.Value = "On";
            end

        end

        % Button pushed function: lsqcurvefitdocumentationButton
        function lsqcurvefitdocumentationButtonPushed(app, event)
            doc lsqcurvefit
        end

        % Button pushed function: docButton_1
        function docButton_1Pushed(app, event)
            app.TextArea.Value = "Termination tolerance on x, a positive scalar.";
        end

        % Button pushed function: docButton_2
        function docButton_2Pushed(app, event)
            app.TextArea.Value = "Termination tolerance on the function value, a positive scalar.";
        end

        % Button pushed function: docButton_3
        function docButton_3Pushed(app, event)
            app.TextArea.Value = "Maximum number of function evaluations allowed, a positive integer.";
        end

        % Button pushed function: docButton_4
        function docButton_4Pushed(app, event)
            app.TextArea.Value = "Maximum number of iterations allowed, a positive integer.";
        end

        % Button pushed function: docButton_5
        function docButton_5Pushed(app, event)
            app.TextArea.Value = "The peakfind function extracts the width of the peak that is fit. This value is multiplied to that returned width, and uses the returned value as the fit interval that is used for each peak.";
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            figure(app.parent_app.UIFigure)
            app.delete;
        end

        % Value changed function: param_1, param_2, param_3, param_4, 
        % param_5, param_6
        function param_1ValueChanged(app, event)
            app.Set_Fields();
        end

        % Button pushed function: docButton_6
        function docButton_6Pushed(app, event)
            app.TextArea.Value = "Hard codes the interval that the fit is performed on for each peak.";
        end

        % Value changed function: UseFitWidthMultiplierSwitch
        function UseFitWidthMultiplierSwitchValueChanged(app, event)
            value = app.UseFitWidthMultiplierSwitch.Value;
            if value == "On"
                hard_coded_bool = 0;
                app.param_6.Enable = "Off";
                app.param_5.Enable = "On";
            else
                hard_coded_bool = 1;
                app.param_6.Enable = "On";
                app.param_5.Enable = "Off";
            end
            app.hard_coded_width = hard_coded_bool;
            app.Set_Fields();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.lsqcurvefitParametersLabel = uilabel(app.UIFigure);
            app.lsqcurvefitParametersLabel.Text = 'lsqcurvefit Parameters';
            app.param_1 = uieditfield(app.UIFigure, 'numeric');
            app.param_1.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_2 = uieditfield(app.UIFigure, 'numeric');
            app.param_2.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_3 = uieditfield(app.UIFigure, 'numeric');
            app.param_3.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_4 = uieditfield(app.UIFigure, 'numeric');
            app.param_4.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.docButton_1 = uibutton(app.UIFigure, 'push');
            app.docButton_1.ButtonPushedFcn = createCallbackFcn(app, @docButton_1Pushed, true);
            app.docButton_1.Text = 'doc';
            app.docButton_2 = uibutton(app.UIFigure, 'push');
            app.docButton_2.ButtonPushedFcn = createCallbackFcn(app, @docButton_2Pushed, true);
            app.docButton_2.Text = 'doc';
            app.docButton_3 = uibutton(app.UIFigure, 'push');
            app.docButton_3.ButtonPushedFcn = createCallbackFcn(app, @docButton_3Pushed, true);
            app.docButton_3.Text = 'doc';
            app.docButton_4 = uibutton(app.UIFigure, 'push');
            app.docButton_4.ButtonPushedFcn = createCallbackFcn(app, @docButton_4Pushed, true);
            app.docButton_4.Text = 'doc';
            app.lsqcurvefitdocumentationButton = uibutton(app.UIFigure, 'push');
            app.lsqcurvefitdocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @lsqcurvefitdocumentationButtonPushed, true);
            app.lsqcurvefitdocumentationButton.Text = 'lsqcurvefit documentation';
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            app.TextArea = uitextarea(app.UIFigure);
            app.param_5 = uieditfield(app.UIFigure, 'numeric');
            app.param_5.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.docButton_5 = uibutton(app.UIFigure, 'push');
            app.docButton_5.ButtonPushedFcn = createCallbackFcn(app, @docButton_5Pushed, true);
            app.docButton_5.Text = 'doc';
            app.param_label_1 = uilabel(app.UIFigure);
            app.param_label_1.Text = 'TolX';
            app.param_label_2 = uilabel(app.UIFigure);
            app.param_label_2.Text = 'TolFun';
            app.param_label_3 = uilabel(app.UIFigure);
            app.param_label_3.Text = 'MaxFunctionEvaluations';
            app.param_label_4 = uilabel(app.UIFigure);
            app.param_label_4.Text = 'MaxIterations';
            app.param_label_5 = uilabel(app.UIFigure);
            app.param_label_5.Text = 'fit_width_multiplier';
            app.GeneralFittingParametersLabel = uilabel(app.UIFigure);
            app.GeneralFittingParametersLabel.Text = 'General Fitting Parameters';
            app.UseFitWidthMultiplierSwitchLabel = uilabel(app.UIFigure);
            app.UseFitWidthMultiplierSwitchLabel.Text = 'Use Fit Width Multiplier';
            app.UseFitWidthMultiplierSwitch = uiswitch(app.UIFigure, 'slider');
            app.UseFitWidthMultiplierSwitch.ValueChangedFcn = createCallbackFcn(app, @UseFitWidthMultiplierSwitchValueChanged, true);
            app.param_6 = uieditfield(app.UIFigure, 'numeric');
            app.param_6.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_label_6 = uilabel(app.UIFigure);
            app.param_label_6.Text = 'fit_width';
            app.docButton_6 = uibutton(app.UIFigure, 'push');
            app.docButton_6.ButtonPushedFcn = createCallbackFcn(app, @docButton_6Pushed, true);
            app.docButton_6.Text = 'doc';
        end
    end

    methods (Access = public)

        % Construct app
        function app = peakfit_param(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end